classdef A2RL_Planner_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        A2RL_UIFigure_              matlab.ui.Figure
        MainGrid                    matlab.ui.container.GridLayout
        RightPanel                  matlab.ui.container.Panel
        Right_Main_Grid             matlab.ui.container.GridLayout
        Tab_Group_                  matlab.ui.container.TabGroup
        Tab_Main                    matlab.ui.container.Tab
        Tab_Main_Grid               matlab.ui.container.GridLayout
        Vel_plot                    matlab.ui.control.UIAxes
        Traj_plot                   matlab.ui.control.UIAxes
        Yaw_plot                    matlab.ui.control.UIAxes
        GG_plot                     matlab.ui.control.UIAxes
        Tab_Each_Lap                matlab.ui.container.Tab
        Tab_Each_Lap_Grid           matlab.ui.container.GridLayout
        Label_lap_time              matlab.ui.control.Label
        nopitButton                 matlab.ui.control.Button
        pitButton                   matlab.ui.control.Button
        Lap_Selector_               matlab.ui.control.Spinner
        Label_16                    matlab.ui.control.Label
        Each_lap_plot               matlab.ui.control.UIAxes
        Tab_Runtime                 matlab.ui.container.Tab
        Tab_Runtime_Grid            matlab.ui.container.GridLayout
        steptime_plot               matlab.ui.control.UIAxes
        Tab_Acc                     matlab.ui.container.Tab
        Tab_Acc_Grid                matlab.ui.container.GridLayout
        Acc_Y_plot                  matlab.ui.control.UIAxes
        Acc_X_plot                  matlab.ui.control.UIAxes
        Tab_Err                     matlab.ui.container.Tab
        Tab_Err_Grid                matlab.ui.container.GridLayout
        Yaw_err_plot                matlab.ui.control.UIAxes
        Lat_err_plot                matlab.ui.control.UIAxes
        Vel_err_plot                matlab.ui.control.UIAxes
        Tab_Tyre_Temp               matlab.ui.container.Tab
        Tab_Tyre_Temp_Grid          matlab.ui.container.GridLayout
        fontstylecolorredfontLabel  matlab.ui.control.Label
        RL_Tyre_plot                matlab.ui.control.UIAxes
        RR_Tyre_plot                matlab.ui.control.UIAxes
        FR_Tyre_plot                matlab.ui.control.UIAxes
        FL_Tyre_plot                matlab.ui.control.UIAxes
        Tab_Slip_Angle              matlab.ui.container.Tab
        Tab_Slip_Angle_Grid         matlab.ui.container.GridLayout
        RR_Slip_Angle               matlab.ui.control.UIAxes
        RL_Slip_Angle               matlab.ui.control.UIAxes
        FR_Slip_Angle               matlab.ui.control.UIAxes
        FL_Slip_Angle               matlab.ui.control.UIAxes
        Tab_Slip_Ratio              matlab.ui.container.Tab
        Tab_Slip_Ratio_Grid         matlab.ui.container.GridLayout
        RR_Slip_Ratio               matlab.ui.control.UIAxes
        RL_Slip_Ratio               matlab.ui.control.UIAxes
        FR_Slip_Ratio               matlab.ui.control.UIAxes
        FL_Slip_Ratio               matlab.ui.control.UIAxes
        Tab_Old_Slip                matlab.ui.container.Tab
        Tab_Old_Slip_Grid           matlab.ui.container.GridLayout
        Old_Rear_Slip_Ratio         matlab.ui.control.UIAxes
        Old_Front_Slip_Ratio        matlab.ui.control.UIAxes
        Old_Rear_Slip_Angle         matlab.ui.control.UIAxes
        Old_Front_Slip_Angle        matlab.ui.control.UIAxes
        Tab_Slip_Compare            matlab.ui.container.Tab
        Slip_Compare_Grid           matlab.ui.container.GridLayout
        Rear_Slip_Angle_Compare     matlab.ui.control.UIAxes
        Rear_Slip_Ratio_Compare     matlab.ui.control.UIAxes
        Front_Slip_Angle_Compare    matlab.ui.control.UIAxes
        Front_Slip_Ratio_Compare    matlab.ui.control.UIAxes
        Tab_Actsteer                matlab.ui.container.Tab
        Actsteer_Grid               matlab.ui.container.GridLayout
        Actsteer_Fig                matlab.ui.control.UIAxes
        Tab_Planning_Acc            matlab.ui.container.Tab
        Planning_Acc_Grid           matlab.ui.container.GridLayout
        Planning_ggv_plot           matlab.ui.control.UIAxes
        Planning_vel_plot           matlab.ui.control.UIAxes
        Planning_path_plot          matlab.ui.control.UIAxes
        Planning_acc_Plot           matlab.ui.control.UIAxes
        Tab_GGV                     matlab.ui.container.Tab
        GGV_Grid                    matlab.ui.container.GridLayout
        GGV_plot                    matlab.ui.control.UIAxes
        LeftPanel                   matlab.ui.container.Panel
        Left_Main_Grid              matlab.ui.container.GridLayout
        Text_Grid                   matlab.ui.container.GridLayout
        Text_s                      matlab.ui.control.EditField
        smEditFieldLabel            matlab.ui.control.Label
        Text_vel                    matlab.ui.control.EditField
        vmsEditFieldLabel           matlab.ui.control.Label
        Text_step_time              matlab.ui.control.EditField
        Label_12                    matlab.ui.control.Label
        Text_vel_error              matlab.ui.control.EditField
        Label_11                    matlab.ui.control.Label
        Text_yaw_error              matlab.ui.control.EditField
        Label_10                    matlab.ui.control.Label
        Text_lat_error              matlab.ui.control.EditField
        Label_9                     matlab.ui.control.Label
        Text_op_vel_flag            matlab.ui.control.EditField
        Label_5                     matlab.ui.control.Label
        Text_op_pos_flag            matlab.ui.control.EditField
        Label_4                     matlab.ui.control.Label
        Text_time                   matlab.ui.control.EditField
        Label_7                     matlab.ui.control.Label
        Text_vel_limit              matlab.ui.control.EditField
        Label_8                     matlab.ui.control.Label
        Text_state                  matlab.ui.control.EditField
        Label_3                     matlab.ui.control.Label
        Text_self_pos               matlab.ui.control.EditField
        Label_6                     matlab.ui.control.Label
        Selector_Grid               matlab.ui.container.GridLayout
        Plot_View_Size_DropDown_    matlab.ui.control.DropDown
        Label_18                    matlab.ui.control.Label
        Max_Cmd_Point_Selector_     matlab.ui.control.Spinner
        Label_2                     matlab.ui.control.Label
        Curr_Idx_Slider_            matlab.ui.control.Slider
        Curr_Step_DropDown_         matlab.ui.control.DropDown
        Label_17                    matlab.ui.control.Label
        Curr_Idx_Selector_          matlab.ui.control.Spinner
        Label_14                    matlab.ui.control.Label
        ReadFile_Grid               matlab.ui.container.GridLayout
        Label_Time_stamp            matlab.ui.control.Label
        File_Label                  matlab.ui.control.Label
        Read_File_Button_           matlab.ui.control.Button
        Title_Grid                  matlab.ui.container.GridLayout
        Myicon                      matlab.ui.control.Image
        AuthorLabel                 matlab.ui.control.Label
        A2RLLabel                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        is_valid_file_read = false
        csv_data_filepath % 数据文件路径
        csv_data_filename % 数据文件名
        csv_data

        track_left_x % 数组，赛道左边界的 x 坐标
        track_left_y % 数组，赛道左边界的 y 坐标
        track_right_x % 数组，赛道右边界的 x 坐标
        track_right_y % 数组，赛道右边界的 y 坐标
        track_color % 颜色值，赛道边界的显示颜色
        
        current_index % 当前展示数据的起始索引
        tab_index % 当前 tab 的索引，从 1 开始，默认为 1
        plot_size = 1; % 浮点数，绘图缩放比例，默认值为 1

        data_index_start % 整数，数据索引的起始值
        data_index_end % 整数，数据索引的结束值
        
        time_stamp  % 时间戳数组
        time_start % 浮点数，数据的时间戳起点
        time_series % 数组，相对时间序列（秒）
        time_now  % 浮点数，当前运行时间
        time_min
        time_s
        time_ms


        est_s % 数组，车辆沿路径的估计弧长（米）
        est_x % 数组，车辆估计 x 坐标（米）
        est_y % 数组，车辆估计 y 坐标（米）
        est_yaw % 数组，车辆估计航向角（弧度）
        est_velocity % 数组，车辆估计速度（米/秒）
        sel_track_mode % 数组，选择的路径模式
        state_machine % 数组，状态机标志
        car_on_where % 数组，车辆位置标志
        rc_speed_percent % 数组，遥控速度百分比
        rc_speed_limit % 数组，遥控速度上限（米/秒）
        follow_distance_min % 数组，最小跟车距离
        follow_distance_rc % 数组，遥控跟车距离
        follow_distance_conf % 数组，配置的跟车距离
        op_path_flag % 数组，路径优化标志
        op_vel_flag % 数组，速度优化标志

        is_cmd_valid % 布尔值，表示指令数据是否有效
        cmd_x % 数组，指令轨迹的 x 坐标（米）
        cmd_y % 数组，指令轨迹的 y 坐标（米）
        cmd_yaw % 数组，指令航向角（弧度）
        cmd_velocity % 数组，指令速度（米/秒）
        acc_Planning % 求解预测30个点的第一个点的速度微分，即规划加速度 (m/s^2)

        is_other_valid % 布尔值，表示其他车辆数据是否有效
        other_x % 数组，其他车辆的 x 坐标（米）
        other_y % 数组，其他车辆的 y 坐标（米）
        other_velocity % 数组，其他车辆的速度（米/秒）
        other_yaw % 数组，其他车辆的航向角（弧度）

        is_err_valid % 布尔值，表示误差数据是否有效
        lateral_error % 数组，横向误差（米）
        yaw_error % 数组，航向误差（弧度）
        speed_error % 数组，速度误差（米/秒）
        
        is_actsteer_valid % 布尔值，表示转角数据是否有效
        actsteer % 数值，转角(度)

        is_slip_valid % 布尔值，表示轮胎滑移数据是否有效
        slip_f % 数组，前轮滑移角
        slip_r % 数组，后轮滑移角

        is_tyre_temp_valid % 布尔值，表示轮胎温度数据是否有效
        tyre_temp_fl_inner % 数组，左前轮胎内侧温度（℃）
        tyre_temp_fl_center % 数组，左前轮胎中心温度（℃）
        tyre_temp_fl_outer % 数组，左前轮胎外侧温度（℃）
        tyre_temp_fr_inner
        tyre_temp_fr_center
        tyre_temp_fr_outer
        tyre_temp_rl_inner
        tyre_temp_rl_center
        tyre_temp_rl_outer
        tyre_temp_rr_inner
        tyre_temp_rr_center
        tyre_temp_rr_outer

        % 新增滑移部分参数 
        slip_angle_fl
        slip_angle_fr
        slip_angle_rl
        slip_angle_rr
        slip_ratio_fl
        slip_ratio_fr
        slip_ratio_rl
        slip_ratio_rr

        slip_angle_front_old
        slip_angle_rear_old
        slip_ratio_front_old
        slip_ratio_rear_old

        pit_show_flag % 显示与不显示pit，由按键决定
        
        pit_left_x
        pit_left_y
        pit_right_x
        pit_right_y

        is_acc_valid % 布尔值，表示参考加速度数据是否有效
        An_ref % 数组，参考纵向加速度（米/秒²）
        Aw_ref % 数组，参考横向加速度（米/秒²）
        Ae0_ref % 数组，参考加速度边界 0
        Ae1_ref % 数组，参考加速度边界 1
        % changed for ggv files
        ae_vmps 
        ae_drive 
        ax_max_v
        ax_max_mps2
        ay1_max_mps2 
        cardata_v
        cardata_axmax
        cardata_aymax
        n_ggv

        hw_ax_max_v
        hw_ax_max_mps2
        hw_ay1_max_mps2 
        
        v_exp
        ax_max_exp
        ay_max_exp
        
        % fix ay >32m/s to constant value 
        v_exp_fix
        ax_max_exp_fix
        ay_max_exp_fix

        is_observer_acc_valid % 布尔值，表示观测加速度数据是否有效
        observer_accx % 数组，观测横向加速度（米/秒²）
        observer_accy % 数组，观测纵向加速度（米/秒²）

        step_elapsed_millisec % 数组，规划耗时（毫秒）

        lap_count % 数组，当前圈数
        lap_s_idx 
        lap_s_change_flag
        max_lap_count % 整数，最大圈数
        lap_time % 当前圈速
        lap_time_series_now %当前圈
        lap_time_min
        lap_time_sec
        lap_time_msec
        
        % traj 
        savedXLim = [];  % 保存X轴范围
        savedYLim = [];  % 保存Y轴范围
        isFirstUpdate = true;  % 首次更新标志

        % Planning path
        Planning_savedXLim = [];  % 保存X轴范围
        Planning_savedYLim = [];  % 保存Y轴范围
        Planning_isFirstUpdate = true;  % 首次更新标志     
    end
    
    methods (Access = private)

        % 读取log的csv表格
        function read_data_file(app)
            
            app.csv_data = readtable([app.csv_data_filepath, app.csv_data_filename]);

            % 检查是否合法
            if (size(app.csv_data, 1) == 0 || size(app.csv_data, 2) == 0)
                app.is_valid_file_read = false;
                return
            end

            if isempty(app.csv_data)
                uialert(app.A2RL_UIFigure_, sprintf("%s 是空文件，请检查", app.csv_data_filename), "警告", "Icon", "warning", "Interpreter", "html")
                app.is_valid_file_read = false;
                return
            end

            % 设置数据初始结束索引
            app.data_index_start = min([1, size(app.csv_data, 1)]); % 取 1 和数据行数的最小值。这确保起始索引至少为 1（MATLAB 索引从 1 开始），但如果文件为空（行数为 0），则仍返回 0。
            app.data_index_end = size(app.csv_data, 1);  % 数据索引行的最大值，即数据的行数
            app.current_index = app.data_index_start;   
            
            % 开始默认开启pitline标志位
            app.pit_show_flag = 1;

            % 当前数据
            try
                app.time_stamp = app.csv_data.timestamp_start;
                app.time_start = app.csv_data.timestamp_start(1);
                app.time_series = app.csv_data.timestamp_start - app.csv_data.timestamp_start(1);
                app.est_s = app.csv_data.n_s;
                app.est_x = app.csv_data.observer_x;
                app.est_y = app.csv_data.observer_y;
                app.est_yaw = app.csv_data.observer_yaw;
                app.est_velocity = app.csv_data.observer_vx_vy;
                % writematrix(app.est_x, 'output_est_x.csv');
                % writematrix(app.est_y, 'output_est_y.csv');
            catch ME
                uialert(app.A2RL_UIFigure_, "缺失关键数据（时间、本车位置、路程、航向、速度等），请检查", "警告", "Icon", "error", "Interpreter", "html")
                app.is_valid_file_read = false;
                return
            end

            % 现在，基本数据已经读完，可以认为读取的文件是有效的
            app.is_valid_file_read = true;
            try app.sel_track_mode = app.csv_data.sel_track_mode; catch; end % 
            try app.state_machine = app.csv_data.race_follow_overtake_flag; catch; end
            try app.car_on_where = app.csv_data.car_on_where; catch; end
            try app.rc_speed_percent = app.csv_data.rc_speed_per; catch; end
            try app.rc_speed_limit = app.csv_data.rc_speed_uplimit; catch; end
            try app.follow_distance_min = app.csv_data.dist_min_value; catch; end
            try app.follow_distance_rc = app.csv_data.follow_distance_remote; catch; end
            try app.follow_distance_conf = app.csv_data.follow_distance_config; catch; end
            try app.op_path_flag = app.csv_data.op_path_flag; catch; end
            try app.op_vel_flag = app.csv_data.op_vel_flag; catch; end
            try 
                app.lateral_error = app.csv_data.lateral_error; 
                app.yaw_error = app.csv_data.yaw_error;
                app.speed_error = app.csv_data.speed_error; 
                app.is_err_valid = true;
                    
            catch ME 
                app.is_err_valid = false;
            end

            try 
                app.slip_f = app.csv_data.slip_f;
                app.slip_r = app.csv_data.slip_r; 
                app.is_slip_valid = true;
            catch
                app.is_slip_valid = false;
            end

            try 
                app.tyre_temp_fl_inner = app.csv_data.tyre_temp_fl_inner;
                app.tyre_temp_fl_center = app.csv_data.tyre_temp_fl_center;
                app.tyre_temp_fl_outer = app.csv_data.tyre_temp_fl_outer;
                app.tyre_temp_fr_inner = app.csv_data.tyre_temp_fr_inner;
                app.tyre_temp_fr_center = app.csv_data.tyre_temp_fr_center;
                app.tyre_temp_fr_outer = app.csv_data.tyre_temp_fr_outer;
                app.tyre_temp_rl_inner = app.csv_data.tyre_temp_rl_inner;
                app.tyre_temp_rl_center = app.csv_data.tyre_temp_rl_center;
                app.tyre_temp_rl_outer = app.csv_data.tyre_temp_rl_outer;
                app.tyre_temp_rr_inner = app.csv_data.tyre_temp_rr_inner;
                app.tyre_temp_rr_center = app.csv_data.tyre_temp_rr_center;
                app.tyre_temp_rr_outer = app.csv_data.tyre_temp_rr_outer;
                % 新增滑移部分参数
                app.slip_angle_fl = app.csv_data.slip_angle_fl;
                app.slip_angle_fr = app.csv_data.slip_angle_fr;
                app.slip_angle_rl = app.csv_data.slip_angle_rl;
                app.slip_angle_rr = app.csv_data.slip_angle_rr;
                app.slip_ratio_fl = app.csv_data.slip_ratio_fl;
                app.slip_ratio_fr = app.csv_data.slip_ratio_fr;
                app.slip_ratio_rl = app.csv_data.slip_ratio_rl;
                app.slip_ratio_rr = app.csv_data.slip_ratio_rr;

                app.slip_angle_front_old = app.csv_data.slip_angle_front_old;
                app.slip_angle_rear_old = app.csv_data.slip_angle_rear_old;
                app.slip_ratio_front_old = app.csv_data.slip_ratio_front_old;
                app.slip_ratio_rear_old = app.csv_data.slip_ratio_rear_old;

                app.is_slip_valid = true;
            catch ME
                app.is_slip_valid = false;
            end

            try
                app.An_ref = app.csv_data.An_ref;
                app.Aw_ref = app.csv_data.Aw_ref;
                app.Ae0_ref = app.csv_data.Ae0_ref;
                app.Ae1_ref = app.csv_data.Ae1_ref;
                app.is_acc_valid = true;
            % 如果出现异常现象matlab exeption
            catch ME
                app.is_acc_valid = false;
            end

            try
                app.observer_accx = app.csv_data.observer_accx;
                app.observer_accy = app.csv_data.observer_accy;
                app.is_observer_acc_valid = true;
            catch ME
                app.is_observer_acc_valid = false;
            end

            try app.step_elapsed_millisec = app.csv_data.step_elapsed_sec * 1000; catch; end
            
            try 
                app.lap_count = app.csv_data.lap_count; 
                app.max_lap_count = max(app.lap_count);
                % writematrix(app.lap_count, 'output_lap_count.csv');
            catch ME
            end
                     
            % 指令数据
           
            try   
                % 提取表头以"x_"开头的所有列元素并保存到数组cmd_x中
                app.cmd_x = table2array(app.csv_data(:, "x_" + digitsPattern));
                % writematrix(app.cmd_x, 'output_cmd_x.csv');
                app.cmd_y = table2array(app.csv_data(:, "y_" + digitsPattern));
                app.cmd_yaw = table2array(app.csv_data(:, "yaw_" + digitsPattern));
                % writematrix(app.cmd_yaw, 'output_cmd_yaw.csv');
                app.cmd_velocity = table2array(app.csv_data(:, "vel_" + digitsPattern));
                % writematrix(app.cmd_velocity, 'output_cmd_vel.csv');
                app.is_cmd_valid = true;

            catch ME
                app.is_cmd_valid = false;
            end

            try 
                app.actsteer = app.csv_data.actsteer;
                app.is_actsteer_valid = true;
            catch ME
                app.is_actsteer_valid = false;
            end

            try 
                app.other_x = app.csv_data.loc_x_npc;
                app.other_y = app.csv_data.loc_y_npc;
                app.other_yaw = app.csv_data.loc_A_npc;
                app.other_velocity = app.csv_data.loc_Vs_npc;
                app.is_other_valid = true;
            catch ME
                app.is_other_valid = false;
            end
        end
        
        % 根据当前tab索引对该tab下的图像进行更新
        function update_select_plot(app)
            % csv文件读取
            if ~app.is_valid_file_read
                return
            end
            % 如果csv文件读取成功，根据当前的tab对该tab下的图像进行更新，跳转到各自的函数中
            switch app.tab_index
                case app.Tab_Main.Title      % 一定要加上这个Title参数，不加的话点击Tab不会进行函数跳转与图像更新
                    app.update_plot_main;
                case app.Tab_Runtime.Title
                    app.update_plot_runtime;
                case app.Tab_Tyre_Temp.Title
                    app.update_plot_tyre_temp;
                case app.Tab_Acc.Title
                    app.update_plot_acc;
                case app.Tab_Err.Title
                    app.update_plot_error;
                case app.Tab_Each_Lap.Title
                    app.update_plot_each_lap;
                case app.Tab_Slip_Angle.Title
                    app.update_plot_slip_angle;
                case app.Tab_Slip_Ratio.Title
                    app.update_plot_slip_ratio;
                case app.Tab_Old_Slip.Title
                    app.update_plot_old_slip;
                case app.Tab_Slip_Compare.Title
                    app.plot_slip_compare;
                case app.Tab_Actsteer.Title
                    app.plot_actsteer;
                case app.Tab_Planning_Acc.Title
                    app.plot_planning_acc;
                case app.Tab_GGV.Title
                    app.plot_ggv;
                case app.Tab_GGV_hw.Title
                    app.plot_ggv_hw;
            end
        end    

        % 读取道路track.mat文件
        function read_track_file(app)
            try
                % 
                % data_with_pit = load("track_data_with_pit.mat"); 
                data_with_pit = load("track_data_with_pit_google.mat");
            catch ME
                uialert(app.A2RL_UIFigure_, "找不到赛道边界的数据文件<br>请将有效赛道数据边界文件命名为<br><b>track_data.mat</b><br>置于本程序同一目录下，并重启程序", "错误", "Icon", "error", "Interpreter", "html", "CloseFcn", @(h, e) app.delete())
                app.Read_File_Button_.Enable = "off";
                app.File_Label.Text = "赛道文件缺失，请检查并重启程序";
                return
            end
    
            try
                track_data = data_with_pit.track_data;
                app.track_left_x = track_data.track_left_x;
                app.track_left_y = track_data.track_left_y;
                app.track_right_x = track_data.track_right_x;
                app.track_right_y = track_data.track_right_y;
                app.pit_left_x = track_data.pit_left_x;
                app.pit_left_y = track_data.pit_left_y;
                app.pit_right_x = track_data.pit_right_x;
                app.pit_right_y = track_data.pit_right_y;
    
            catch ME
                uialert(app.A2RL_UIFigure_, "赛道边界文件内容缺失，其必须包含：<br><b>track_left_x</b>、<b>track_left_y</b>、<b>track_right_x</b>、<br><b>track_right_y</b> 请检查修复后重启程序", "错误", "Icon", "error", "Interpreter", "html", "CloseFcn", @(h, e) app.delete())
                app.Read_File_Button_.Enable = "off";
                app.File_Label.Text = "赛道文件损坏，请检查";
                return
            end
        end
        
        % 读取本地ggv边界
        function read_ggv_boundary(app)
             % 读取发动机边界
             try
                % ax_engine_data % speed ax_drive
                % ggv_file_data  % v_mps ax_max_mps2 ay1_max_mps2
                ax_engine = readtable('ax_drive_vs_speed_a2rl2025.csv');
                ggv_file = readtable('ggv_zx.csv');
                gg_hw = readtable("gg_hw_0820.csv");
                gg_cardata = readtable('gg_CarData.csv');
                gg_exp = readtable('gg_ax_zx_ay_hw_0820_fix.csv');
                % gg_exp_fix = readtable('gg_ax_zx_ay_hw_0820_fix.csv');
             catch ME
                uialert(app.A2RL_UIFigure_, "找不到对应的发动机边界文件和GGV文件", "错误", "Icon", "error", "Interpreter", "html", "CloseFcn", @(h, e) app.delete())
                app.Read_File_Button_.Enable = "off";
                app.File_Label.Text = "Ax_engine,ggv_zx文件缺失，请检查并重启程序";
                return 
             end
             
                app.ae_vmps = ax_engine.speed;
                app.ae_drive = ax_engine.ax_drive;
                app.ax_max_v = ggv_file.v_mps; 
                app.ax_max_mps2 = ggv_file.ax_max_mps2;
                app.ay1_max_mps2 = ggv_file.ay1_max_mps2;
                app.n_ggv = ggv_file.n;
                app.cardata_v = gg_cardata.v_mps;
                app.cardata_axmax = gg_cardata.ax_max_mps2;
                app.cardata_aymax = gg_cardata.ay1_max_mps2;

                app.hw_ax_max_v = gg_hw.v_mps; 
                app.hw_ax_max_mps2 = gg_hw.ax_max_mps2;
                app.hw_ay1_max_mps2 = gg_hw.ay1_max_mps2;
                
                app.v_exp = gg_exp.v_mps;
                app.ax_max_exp = gg_exp.ax_max_mps2;
                app.ay_max_exp = gg_exp.ay1_max_mps2;    
                
                % fix ay >32m/s to constant value 
                % app.v_exp_fix = gg_exp_fix.v_mps;
                % app.ax_max_exp_fix = gg_exp_fix.ax_max_mps2;
                % app.ay_max_exp_fix = gg_exp_fix.ay1_max_mps2;                  

        end

        % 使能部分组件
        function enable_components(app) 
            app.Curr_Idx_Selector_.Enable = "on";
            app.Curr_Idx_Slider_.Enable = "on";
            app.Max_Cmd_Point_Selector_.Enable = "on";
            app.Curr_Step_DropDown_.Enable = "on";
            app.Lap_Selector_.Enable = "on";
            app.Plot_View_Size_DropDown_.Enable = "on";

            % app.pitButton.Enable = "on";
            app.nopitButton.Enable = "on";
        end
        
        % 失能部分组件
        function disable_components(app)
            app.Curr_Idx_Selector_.Enable = "off";
            app.Curr_Idx_Slider_.Enable = "off";
            app.Max_Cmd_Point_Selector_.Enable = "off";
            app.Curr_Step_DropDown_.Enable = "off";
            app.Lap_Selector_.Enable = "off";
            app.Plot_View_Size_DropDown_.Enable = "off";

            app.pitButton.Enable = "off";
            % app.nopitButton.Enable = "off";
        end
        
        % 设置组件可选范围限制
        function init_index_limit(app)
            app.Curr_Idx_Selector_.Limits = [app.data_index_start, app.data_index_end];
            app.Curr_Idx_Slider_.Limits = [app.data_index_start, app.data_index_end];
            app.Max_Cmd_Point_Selector_.Limits = [min([1, size(app.cmd_x, 2)]), size(app.cmd_x, 2)]; % 
            app.Lap_Selector_.Limits(2) = app.max_lap_count+1; % Limits(2) indicates the max value
        end
        
        % 初始化当前组件的值
        function init_index_value(app)
            app.Curr_Idx_Selector_.Value = app.data_index_start;
            app.Curr_Idx_Slider_.Value = app.data_index_start;
            app.Max_Cmd_Point_Selector_.Value = size(app.cmd_x, 2);
            app.lap_s_change_flag = 0;
        end
        
        % 设定APP在屏幕居中显示并设定大小
        function make_app_center(app)
            % position_screen是电脑屏幕的相关信息，position_app是生成app的相关信息
            position_screen = get(0, "ScreenSize"); % 获取当前屏幕的尺寸，存储在 position_screen 中
            position_app = app.A2RL_UIFigure_.Position;  % position是一个四元素，表示应用程序主窗口的位置和大小[left, bottom, width, height]
            position_app(3:4) = position_screen(3:4) * 0.6;
            position_app(1) = (position_screen(3)-position_app(3)) / 2;
            position_app(2) = (position_screen(4)-position_app(4)) / 2;   
            app.A2RL_UIFigure_.Position = position_app;
        end
       
        % 根据车辆当前在路径上的位置标志位car_on_where返回位置字符串
        function ret = parse_self_pos(app)
            try
                switch (app.car_on_where(app.current_index))
                    case 0
                        ret = "赛车道";
                    case 1
                        ret = "Pit 1";
                    case 2
                        ret = "Pit 2";
                    otherwise
                        ret = "未定义";
                end  
            catch ME
                ret = "无数据";
            end
        end
        
        % 返回当前位置的状态机字符串
        function ret = parse_state_machine(app)
            try
                switch (app.state_machine(app.current_index))
                    case 1
                        ret = "局部规划";
                    case 2
                        ret = "跟车";
                    case 3
                        ret = "全局规划";   
                    case 4
                        ret = "Pit 模式";
                    case 5
                        ret = "左路径";
                    case 6
                        ret = "右路径";
                    case 7
                        ret = "锁定跟车";
                    case 8
                        ret = "中路径";
                    case 9
                        ret = "left2规划";
                    case 10 
                        ret = "right2规划";
                    otherwise
                        ret = "未定义";
                end 
            catch ME
                ret = "无数据";
            end
        end
         
        % 解析数值，单位变化
        function ret = parse_num_value(app, thefield)
            try
                % based on thefield, get the thefield's value
                ret = app.(thefield)(app.current_index);
                
                % choose different thefield and makse different change
                if (thefield == "rc_speed_percent")
                    ret = ret * 100;
                end
                
                if (thefield == "yaw_error")
                    ret = rad2deg(ret);
                end
                
                % due to return string and num2str
                ret = num2str(ret);
            catch ME
                ret = "无数据";
            end
        end
        
        % 绘制道路边界图
        function plot_track_boundary(app, ax_handle)   
            % scatter(ax_handle, app.track_left_x, app.track_left_y, 60, 'k', ".")
            % scatter(ax_handle, app.track_right_x, app.track_right_y, 60, 'k', ".")
            
            if ~app.pit_show_flag
                % Plot track boundaries
                plot(ax_handle, app.track_left_x, app.track_left_y, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 2);
                plot(ax_handle, app.track_right_x, app.track_right_y, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 2);
                % Set axis limits to ensure all points are visible
                all_x = [app.track_left_x; app.track_right_x];
                all_y = [app.track_left_y; app.track_right_y];
                axis(ax_handle, [min(all_x), max(all_x), min(all_y), max(all_y)]);
            else 
                % Plot track boundaries
                plot(ax_handle, app.track_left_x, app.track_left_y, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 2);
                plot(ax_handle, app.track_right_x, app.track_right_y, 'Color', 'k', 'LineStyle', '-', 'LineWidth', 2);
                plot(ax_handle, app.pit_left_x, app.pit_left_y, 'Color', 'k', 'LineStyle', ':', 'LineWidth', 2);
                plot(ax_handle, app.pit_right_x, app.pit_right_y, 'Color', 'k', 'LineStyle', ':', 'LineWidth', 2);
                % Set axis limits to ensure all points are visible
                all_x = [app.track_left_x; app.track_right_x];
                all_y = [app.track_left_y; app.track_right_y];
                axis(ax_handle, [min(all_x), max(all_x), min(all_y), max(all_y)]);
            end
        end
        
        % 绘制关键图
        function update_plot_main(app)
            if ~app.isFirstUpdate
                app.savedXLim = app.Traj_plot.XLim;
                app.savedYLim = app.Traj_plot.YLim;
            end

            end_index = min([app.current_index + app.Max_Cmd_Point_Selector_.Value-1, app.data_index_end]);
            end_index_yuce = app.Max_Cmd_Point_Selector_.Value;

            cla(app.Traj_plot)
           

            % 1. 当前车辆的估计位置和方向（est_x, est_y, est_yaw）

            quiver(app.Traj_plot, ...
                 app.est_x(app.current_index,1), ...
                 app.est_y(app.current_index,1), ...
                 cos(app.est_yaw(app.current_index,1)), sin(app.est_yaw(app.current_index,1)), ...
                 'Color', [1, 0, 0], 'Marker', 'o', 'LineWidth', 1.5, ...
                 'AutoScaleFactor', 5); 
            
            % 2. 指令轨迹的位置和方向（cmd_x, cmd_y, cmd_yaw）
            if (app.is_cmd_valid)
                quiver(app.Traj_plot, ...
                    app.cmd_x(app.current_index,1:end_index_yuce), ...
                    app.cmd_y(app.current_index,1:end_index_yuce), ...
                    cos(app.cmd_yaw(app.current_index,1:end_index_yuce)), sin(app.cmd_yaw(app.current_index,1:end_index_yuce)), ...
                    'Color', [0, 0, 1], 'Marker', '.', 'LineWidth', 1, 'MarkerSize', 14, ...
                    'AutoScaleFactor', 0.1); 
                
                % axis_len = vecnorm([app.cmd_x(app.current_index:end_index) - app.est_x(app.current_index), app.cmd_y(app.current_index:end_index) - app.est_y(app.current_index)], 2, 2);
            end
            
            % 3. 其他车辆的位置和方向（other_x, other_y, other_yaw）
            if (app.other_x(app.current_index) ~= 0 || app.other_y(app.current_index) ~= 0) && (app.is_other_valid)
                quiver(app.Traj_plot, ...
                     app.other_x(app.current_index), ...
                     app.other_y(app.current_index), ...
                     cos(app.other_yaw(app.current_index)), sin(app.other_yaw(app.current_index)), ...
                     'Color', [1, 0, 1], 'Marker', 'x', 'LineWidth', 1.5, ...
                     'AutoScaleFactor', 0.3); 
            end
            app.plot_track_boundary(app.Traj_plot)
            % axis(app.Traj_plot, "equal")
           
            % 智能视图管理
            if app.isFirstUpdate
                % 首次更新：使用自动缩放
                axis_len = 100 * app.plot_size;
                xlim(app.Traj_plot, [-axis_len, axis_len] / 2 + app.est_x(app.current_index))
                ylim(app.Traj_plot, [-axis_len, axis_len] / 2 + app.est_y(app.current_index))
                app.isFirstUpdate = false;
            else
                % 非首次更新：保持缩放比例，平移视图
                current_x = app.est_x(app.current_index);
                current_y = app.est_y(app.current_index);
                
                % 计算当前视图的范围（保持缩放比例不变）
                x_range = app.savedXLim(2) - app.savedXLim(1);
                y_range = app.savedYLim(2) - app.savedYLim(1);
                
                % 以车辆为中心设置新的视图范围（保持原有缩放比例）
                new_xlim = [current_x - x_range/2, current_x + x_range/2];
                new_ylim = [current_y - y_range/2, current_y + y_range/2];
                
                app.Traj_plot.XLim = new_xlim;
                app.Traj_plot.YLim = new_ylim;
                
                % 更新保存的视图范围
                app.savedXLim = new_xlim;
                app.savedYLim = new_ylim;
            end

            cla(app.Vel_plot)
            plot(app.Vel_plot, ...
                app.time_series(app.current_index), ...
                app.est_velocity(app.current_index,1), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);

            plot(app.Vel_plot, ...
                app.time_series(app.current_index:end_index), ...
                app.cmd_velocity(app.current_index,1:end_index_yuce), ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 14); 
            
            cla(app.Yaw_plot)
            plot(app.Yaw_plot, ...
                app.time_series(app.current_index), ...
                app.est_yaw(app.current_index,1), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);

            plot(app.Yaw_plot, ...
                app.time_series(app.current_index:end_index), ...
                app.cmd_yaw(app.current_index,1:end_index_yuce), ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 14); 

            
            cla(app.GG_plot)
            % 在坐标系中绘制当前加速度（ax, ay）的红色圆圈
            if (app.is_observer_acc_valid)
                plot(app.GG_plot, ...
                    app.observer_accy(app.current_index), ...
                    app.observer_accx(app.current_index), ...
                    'Color', [1, 0, 0], 'LineStyle', '-', ...
                    'Marker', 'o', 'LineWidth', 1.5);
            end
            
            % 绘制ggv蓝色散点边界
            if (app.is_acc_valid) 
                % 获取 axmax 和 axenegy（假设为 App 属性，替换为实际控件名）
                % axmax = app.Aw_ref(app.current_index); 
                % axenegy = app.Ae0_ref(app.current_index);
                
                % ax_engine_data % speed ax_drive
                % ggv_file_data  % v_mps ax_max_mps2 ay1_max_mps2
                % Cardata  % v_mps  ax_max_mps2  ay1_max_mps2  % 100708
                axenegy = extrap_interp1(app.ae_vmps, app.ae_drive, app.est_velocity(app.current_index));
                axmax_exp = extrap_interp1(app.v_exp, app.ax_max_exp, app.est_velocity(app.current_index));
                aymax_exp = extrap_interp1(app.v_exp, app.ay_max_exp, app.est_velocity(app.current_index));

                % axmax_exp_fix = extrap_interp1(app.v_exp, app.ax_max_exp_fix, app.est_velocity(app.current_index));
                % aymax_exp_fix = extrap_interp1(app.v_exp, app.ay_max_exp, app.est_velocity(app.current_index));
                
                % 椭圆参数
                a_exp = 0.9 * aymax_exp;
                b_exp = 0.7 * axmax_exp; 
                xc = 0; % 中心 x 坐标
                yc = 0; % 中心 y 坐标
                
                theta = linspace(0, 2*pi, 500); 

                % =========================== n = 0.9 ====================
                % x = a * (abs(cos(t)).^(2/exp_val)) .* sign(cos(t));
                % y = b * (abs(sin(t)).^(2/exp_val)) .* sign(sin(t));
                % 070908
                exp_val = 0.8;
                x_exp = xc + a_exp * (abs(cos(theta)).^(2/exp_val)) .* sign(cos(theta));
                y_exp = yc + b_exp * (abs(sin(theta)).^(2/exp_val)) .* sign(sin(theta));
                % 061009
                exp_val = 0.9;
                a_exp = 1.0 * aymax_exp;
                b_exp = 0.6 * axmax_exp;
                x_exp_2 = xc + a_exp * (abs(cos(theta)).^(2/exp_val)) .* sign(cos(theta));
                y_exp_2 = yc + b_exp * (abs(sin(theta)).^(2/exp_val)) .* sign(sin(theta));


                % % ================================0708========================================
                % if axenegy < b07
                %     if abs(axenegy - yc) <= b07
                %         discriminant = 1 - ((axenegy - yc)^2) / (b07^2);
                %         if discriminant >= 0
                %             x10708 = xc - a08 * sqrt(discriminant); % 右端点
                %             x20708 = xc + a08 * sqrt(discriminant); % 左端点
                %         else
                %             fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                %         end
                %     else
                %         fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b07, yc + b07);
                %     end
                % 
                %     % 找到满足条件的索引
                %     idx0708 = (x0708 >= x10708) & (x0708 <= x20708) & (y0708 > axenegy);
                %     % 将对应 y 值替换为 axenegy
                %     y0708(idx0708) = axenegy;
                % end

                % % 080908
                % exp_val = 0.8;                
                % a_exp = 0.9 * aymax_exp;
                % b_exp = 0.8 * axmax_exp;
                % x_exp_3 = xc + a_exp * (abs(cos(theta)).^(2/exp_val)) .* sign(cos(theta));
                % y_exp_3 = yc + b_exp * (abs(sin(theta)).^(2/exp_val)) .* sign(sin(theta));
                % % 071008
                % exp_val = 0.8;
                % a_exp = 1.0 * aymax_exp;
                % b_exp = 0.7 * axmax_exp;
                % x_exp_4 = xc + a_exp * (abs(cos(theta)).^(2/exp_val)) .* sign(cos(theta));
                % y_exp_4 = yc + b_exp * (abs(sin(theta)).^(2/exp_val)) .* sign(sin(theta));
                                        
                % ggv_1010 = plot(app.GG_plot, x, y, 'r', 'LineWidth', 2,'DisplayName', 'ggv1010');
                % ggv_1010_dia = plot(app.GG_plot, x_dia, y_dia, 'b', 'LineWidth', 2,'DisplayName', 'ggv1010菱形');
                % % ggv_1010_exp = plot(app.GG_plot, x_exp, y_exp, 'b:', 'LineWidth', 2,'DisplayName', 'ggv1010_n');
                % ggv_0608 = plot(app.GG_plot, x06, y06, 'k', 'LineWidth', 2,'DisplayName', 'ggv0608');
                % ggv_0708 = plot(app.GG_plot, x07, y07, 'c:', 'LineWidth', 2, 'DisplayName','ggv0708');
                % ggv_cardata = plot(app.GG_plot, xCar, yCar,'g:', 'LineWidth', 2,'DisplayName','cardata100708');
                % legend(app.GG_plot,[ggv_1010, ggv_1010_dia, ggv_0608, ggv_0708, ggv_cardata],'Interpreter','latex','FontSize',10);
                % xlim(app.GG_plot,[-a*1.1 a*1.1]); 
                % if axenegy < a
                %     ylim(app.GG_plot,[-b*1.1 axenegy*1.1]);
                % else
                %     ylim(app.GG_plot,[-b*1.1 b*1.1]);
                % end

                % ggv_0709exp08 = plot(app.GG_plot, x_exp, y_exp, 'b', 'LineWidth', 2,'DisplayName', 'ggv0709_exp0.8');
                ggv_0610exp09 = plot(app.GG_plot, x_exp_2, y_exp_2, 'r', 'LineWidth', 2,'DisplayName', 'ggv0610_exp0.9');
                % ggv_0809exp08 = plot(app.GG_plot, x_exp_3, y_exp_3, 'c', 'LineWidth', 2,'DisplayName', 'ggv0809_exp0.8');
                % ggv_0710exp08 = plot(app.GG_plot, x_exp_4, y_exp_4, 'm', 'LineWidth', 2,'DisplayName', 'ggv0710_exp0.8');

                % % ggv_0610exp_fix
                % legend(app.GG_plot,[ggv_0709exp08, ggv_0709exp09, ggv_0809exp08, ggv_0710exp08],'Interpreter','latex','FontSize',6);
                
                % ggv_0610exp_fix
                legend(app.GG_plot,[ggv_0610exp09],'Interpreter','latex','FontSize',6);
               
            end
   
            app.update_info;
        end
        
        % 绘制每圈轨迹（选择第几圈）
        function update_plot_each_lap(app)        
            cla(app.Each_lap_plot)
            % 先跟据当前的索引判断该索引下的lap_count是否等于Lap_Selector_.Value-1的值，如果相等，就直接画图，
            % 如果不相等，就将该索引下的lap_count+1赋值给Lap_Selector_.Value
            if app.lap_count(app.current_index) ~= app.Lap_Selector_.Value-1
                app.Lap_Selector_.Value = app.lap_count(app.current_index)+1;
            end
            
            % 显示当前圈速label
            app.lap_time_series_now = app.time_series(app.lap_count == app.Lap_Selector_.Value-1); % 当前所在圈的时间数组
            app.lap_time = app.lap_time_series_now(end) - app.lap_time_series_now(1); % 当前所在圈的圈速value
            app.lap_time_min = floor(app.lap_time/60);  % min
            app.lap_time_sec = floor(app.lap_time - app.lap_time_min * 60); % s
            app.lap_time_msec = round(app.lap_time - app.lap_time_min * 60 - app.lap_time_sec); %  ms
            app.Label_lap_time.Text = sprintf('当前圈速: %sm %ss %sms', num2str(app.lap_time_min), ...
                                                              num2str(app.lap_time_sec),num2str(app.lap_time_msec)); % 当前前所在圈的圈速xxminxxsxxms

            % 这里修改每圈轨迹中的轨迹的曲线，可以修改为直线或者散点图
            plot(app.Each_lap_plot, ...
                app.est_x(app.lap_count == app.Lap_Selector_.Value-1), ...
                app.est_y(app.lap_count == app.Lap_Selector_.Value-1), ...
                'Color', [0 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 10); 

            % 绘制当前位置
            plot(app.Each_lap_plot, ...
                app.est_x(app.current_index), ...
                app.est_y(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5, 'MarkerSize', 15);   

            app.plot_track_boundary(app.Each_lap_plot)
            axis(app.Each_lap_plot, "equal")

            app.update_info;
            
        end

        % 绘制每次求解时的规划时间散点图
        function update_plot_runtime(app)
            cla(app.steptime_plot)
            % 绘制csv文件中每一个求解结果的优化求解时间
            plot(app.steptime_plot, app.time_series, app.step_elapsed_millisec, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前求解结果所用优化求解时间
            plot(app.steptime_plot, ...
                app.time_series(app.current_index), ...
                app.step_elapsed_millisec(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);            
            app.update_info;
        end
        
        % 绘制加速度曲线
        function update_plot_acc(app)
            cla(app.Acc_X_plot)
            % 绘制整个路径上的x方向加速度
            x_idx = linspace(app.data_index_start, app.data_index_end,length(app.time_series));
            plot(app.Acc_X_plot, x_idx, app.observer_accx, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5);
            % 绘制当前位置的x方向加速度
            plot(app.Acc_X_plot, ...
                x_idx(app.current_index), ...
                app.observer_accx(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5, 'MarkerSize', 8); 

            cla(app.Acc_Y_plot)
            % 绘制整个路径上的y方向加速度
            plot(app.Acc_Y_plot, x_idx, app.observer_accy, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5);
            % 绘制当前位置的y方向加速度
            plot(app.Acc_Y_plot, ...
                x_idx(app.current_index), ...
                app.observer_accy(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5, 'MarkerSize', 8);
            app.update_info;
        end
        
        % 绘制误差曲线
        function update_plot_error(app)
            cla(app.Vel_err_plot)
            % 绘制整个路径上的速度误差散点图
            plot(app.Vel_err_plot, app.time_series, app.speed_error, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 8)
            % 绘制当前位置的速度误差
            plot(app.Vel_err_plot, ...
                app.time_series(app.current_index), ...
                app.speed_error(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);   

            cla(app.Lat_err_plot)
            % 绘制整个路径上的横向加速度误差散点图
            plot(app.Lat_err_plot, app.time_series, app.lateral_error, ...
                'Color', [0, 0, 1], 'LineStyle', ':', ...
                'Marker', '.', 'LineWidth', 1, 'MarkerSize', 8)
            % 绘制当前位置的横向加速度误差
            plot(app.Lat_err_plot, ...
                app.time_series(app.current_index), ...
                app.lateral_error(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);  

            cla(app.Yaw_err_plot)
            % 绘制整个路径上的航向角误差散点图
            plot(app.Yaw_err_plot, app.time_series, app.yaw_error, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 8)
            % 绘制当前位置的航向角误差
            plot(app.Yaw_err_plot, ...
                app.time_series(app.current_index), ...
                app.yaw_error(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5); 
            app.update_info;
        end
        
        % 绘制轮胎温度曲线
        function update_plot_tyre_temp(app)
            cla(app.FL_Tyre_plot)
            
            plot(app.FL_Tyre_plot, app.time_series, app.tyre_temp_fl_center, ...
                'Color', [1, 0, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.FL_Tyre_plot, app.time_series, app.tyre_temp_fl_inner, ...
                'Color', [0, 1, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.FL_Tyre_plot, app.time_series, app.tyre_temp_fl_outer, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
                % 绘制左前轮当前中心温度
                plot(app.FL_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_fl_center(app.current_index), 'Color','k', 'LineStyle', '-',  'Marker', 'o', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制左前轮当前中心温度
                plot(app.FL_Tyre_plot, app.time_series(app.current_index), app.tyre_temp_fl_inner(app.current_index), 'Color', 'k', 'LineStyle', '-', 'Marker', 's', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制左前轮当前中心温度
                plot(app.FL_Tyre_plot, app.time_series(app.current_index), app.tyre_temp_fl_outer(app.current_index),  'Color','k', 'LineStyle', '-', 'Marker', '^', 'LineWidth', 2, 'MarkerSize', 10);

            cla(app.FR_Tyre_plot)
            plot(app.FR_Tyre_plot, app.time_series, app.tyre_temp_fr_center, ...
                'Color', [1, 0, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.FR_Tyre_plot, app.time_series, app.tyre_temp_fr_inner, ...
                'Color', [0, 1, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.FR_Tyre_plot, app.time_series, app.tyre_temp_fr_outer, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
                % 绘制FR轮当前中心温度
                plot(app.FR_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_fr_center(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', 'o', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制FR轮当前中心温度
                plot(app.FR_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_fr_inner(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', 's', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制FR轮当前中心温度
                plot(app.FR_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_fr_outer(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', '^', 'LineWidth', 2, 'MarkerSize', 10);


            cla(app.RL_Tyre_plot)
            plot(app.RL_Tyre_plot, app.time_series, app.tyre_temp_rl_center, ...
                'Color', [1, 0, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.RL_Tyre_plot, app.time_series, app.tyre_temp_rl_inner, ...
                'Color', [0, 1, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.RL_Tyre_plot, app.time_series, app.tyre_temp_rl_outer, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
                % 绘制RL轮当前中心温度
                plot(app.RL_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_rl_center(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', 'o', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制RL轮当前中心温度
                plot(app.RL_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_rl_inner(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', 's', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制RL轮当前中心温度
                plot(app.RL_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_rl_outer(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', '^', 'LineWidth', 2, 'MarkerSize', 10);

            cla(app.RR_Tyre_plot)
            plot(app.RR_Tyre_plot, app.time_series, app.tyre_temp_rr_center, ...
                'Color', [1, 0, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.RR_Tyre_plot, app.time_series, app.tyre_temp_rr_inner, ...
                'Color', [0, 1, 0], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            plot(app.RR_Tyre_plot, app.time_series, app.tyre_temp_rr_outer, ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
                % 绘制RR轮当前中心温度
                plot(app.RR_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_rr_center(app.current_index), 'Color','k', 'LineStyle', '-',  'Marker', 'o', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制RR轮当前中心温度
                plot(app.RR_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_rr_inner(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', 's', 'LineWidth', 2, 'MarkerSize', 10);
                % 绘制RR轮当前中心温度
                plot(app.RR_Tyre_plot,  app.time_series(app.current_index), app.tyre_temp_rr_outer(app.current_index), 'Color', 'k', 'LineStyle', '-',  'Marker', '^', 'LineWidth', 2, 'MarkerSize', 10);
            app.update_info;
        end
        
        % 绘制滑移角曲线
        function update_plot_slip_angle(app)
            cla(app.FL_Slip_Angle)
            plot(app.FL_Slip_Angle, app.time_series, app.slip_angle_fl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移角
            plot(app.FL_Slip_Angle, ...
                app.time_series(app.current_index), ...
                app.slip_angle_fl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);   

            cla(app.FR_Slip_Angle)
            plot(app.FR_Slip_Angle, app.time_series, app.slip_angle_fr, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移角
            plot(app.FR_Slip_Angle, ...
                app.time_series(app.current_index), ...
                app.slip_angle_fr(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);   


            cla(app.RL_Slip_Angle)
            plot(app.RL_Slip_Angle, app.time_series, app.slip_angle_rl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移角
            plot(app.RL_Slip_Angle, ...
                app.time_series(app.current_index), ...
                app.slip_angle_rl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);  

            cla(app.RR_Slip_Angle)
            plot(app.RR_Slip_Angle, app.time_series, app.slip_angle_rr, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移角
            plot(app.RR_Slip_Angle, ...
                app.time_series(app.current_index), ...
                app.slip_angle_rr(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);  
            app.update_info;
        end

        % 绘制滑移率曲线
        function update_plot_slip_ratio(app)
            cla(app.FL_Slip_Ratio)
            plot(app.FL_Slip_Ratio, app.time_series, app.slip_ratio_fl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移率
            plot(app.FL_Slip_Ratio, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_fl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);  

            cla(app.FR_Slip_Ratio)
            plot(app.FR_Slip_Ratio, app.time_series, app.slip_ratio_fr, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移率
            plot(app.FR_Slip_Ratio, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_fr(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);  


            cla(app.RL_Slip_Ratio)
            plot(app.RL_Slip_Ratio, app.time_series, app.slip_ratio_rl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移率
            plot(app.RL_Slip_Ratio, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_rl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);  

            cla(app.RR_Slip_Ratio)
            plot(app.RR_Slip_Ratio, app.time_series, app.slip_ratio_rr, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移率
            plot(app.RR_Slip_Ratio, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_rr(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5); 
            app.update_info;
        end

        % 绘制旧滑移曲线
        function update_plot_old_slip(app)
            cla(app.Old_Front_Slip_Angle)
            plot(app.Old_Front_Slip_Angle, app.time_series, app.slip_angle_front_old, ...
                'Color',[0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移angle
            plot(app.Old_Front_Slip_Angle, ...
                app.time_series(app.current_index), ...
                app.slip_angle_front_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);

            cla(app.Old_Rear_Slip_Angle)
            plot(app.Old_Rear_Slip_Angle, app.time_series, app.slip_angle_rear_old, ...
                'Color', [0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
            % 绘制当前位置的滑移angle
            plot(app.Old_Rear_Slip_Angle, ...
                app.time_series(app.current_index), ...
                app.slip_angle_rear_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);


            cla(app.Old_Front_Slip_Ratio)
            plot(app.Old_Front_Slip_Ratio, app.time_series, app.slip_ratio_front_old, ...
                'Color', [0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移ratio
            plot(app.Old_Front_Slip_Ratio, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_front_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);


            cla(app.Old_Rear_Slip_Ratio)
            plot(app.Old_Rear_Slip_Ratio, app.time_series, app.slip_ratio_rear_old, ...
                'Color', [0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
            % 绘制当前位置的滑移ratio
            plot(app.Old_Rear_Slip_Ratio, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_rear_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);
            app.update_info;
        end
        
        % 新旧滑移对比
        function plot_slip_compare(app)
            % 前轮滑移率对比
            cla(app.Front_Slip_Ratio_Compare)
            % 新左前轮滑移率plot
            plot(app.Front_Slip_Ratio_Compare, app.time_series, app.slip_ratio_fl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
            plot(app.Front_Slip_Ratio_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_fl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5 ,'MarkerSize', 10);
            % 旧前轮滑移率plot
            plot(app.Front_Slip_Ratio_Compare, app.time_series, app.slip_ratio_front_old, ...
                'Color', [0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
            plot(app.Front_Slip_Ratio_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_front_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 's', 'LineWidth', 1.5, 'MarkerSize', 10)
            
            % 前轮滑移角对比
            cla(app.Front_Slip_Angle_Compare)
            % 新左前轮滑移角plot
            plot(app.Front_Slip_Angle_Compare, app.time_series, app.slip_angle_fl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
            plot(app.Front_Slip_Angle_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_angle_fl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5 ,'MarkerSize', 10);
            % 旧前轮滑移角plot
            plot(app.Front_Slip_Angle_Compare, app.time_series, app.slip_angle_front_old, ...
                'Color', [0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6)
             plot(app.Front_Slip_Angle_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_angle_front_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 's', 'LineWidth', 1.5, 'MarkerSize', 10);

            % 后轮滑移率对比
            cla(app.Rear_Slip_Ratio_Compare)
            % 新左后轮滑移率plot
            plot(app.Rear_Slip_Ratio_Compare, app.time_series, app.slip_ratio_rl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
            plot(app.Rear_Slip_Ratio_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_rl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5 ,'MarkerSize', 10);
            % 旧后轮滑移率plot
            plot(app.Rear_Slip_Ratio_Compare, app.time_series, app.slip_ratio_rear_old, ...
                'Color', [0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
             plot(app.Rear_Slip_Ratio_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_ratio_rear_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 's', 'LineWidth', 1.5, 'MarkerSize', 10);

            % 后轮滑移角对比
            cla(app.Rear_Slip_Angle_Compare)
            % 新左后轮滑移角plot
            plot(app.Rear_Slip_Angle_Compare, app.time_series, app.slip_angle_rl, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
            plot(app.Rear_Slip_Angle_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_angle_rl(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5 ,'MarkerSize', 10);
            % 旧后轮滑移角plot
            plot(app.Rear_Slip_Angle_Compare, app.time_series, app.slip_angle_rear_old, ...
                'Color', [0.49,0.18,0.56], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
             plot(app.Rear_Slip_Angle_Compare, ...
                app.time_series(app.current_index), ...
                app.slip_angle_rear_old(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 's', 'LineWidth', 1.5, 'MarkerSize', 10);
            app.update_info;
        end
        
        % 绘制转角散点图
        function plot_actsteer(app) 
            cla(app.Actsteer_Fig)
            % 转角limits
            plot(app.Actsteer_Fig, app.time_series, -25*ones(size(app.time_series)), ...
                'Color', 'g', 'LineStyle', ':', 'LineWidth', 1.2);
            plot(app.Actsteer_Fig, app.time_series, 25*ones(size(app.time_series)), ...
                'Color', 'g', 'LineStyle', ':', 'LineWidth', 1.2);
            % 绘制整个路径上的转角散点图
            plot(app.Actsteer_Fig, app.time_series, app.actsteer, ...
                'Color', 'b', 'LineStyle', ':', ...
                'Marker', '.', 'LineWidth', 1, 'MarkerSize', 6);
            % 绘制当前位置的滑移率
            plot(app.Actsteer_Fig, ...
                app.time_series(app.current_index), ...
                app.actsteer(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5); 
            ylim(app.Actsteer_Fig,[-30,30]);

            app.update_info;
        end

        % 绘制规划加速度散点图
        function plot_planning_acc(app)
            % 绘制规划路径
            if ~app.Planning_isFirstUpdate
               app.Planning_savedXLim = app.Planning_path_plot.XLim;
               app.Planning_savedYLim = app.Planning_path_plot.YLim;
            end
            end_index = min([app.current_index + app.Max_Cmd_Point_Selector_.Value-1, app.data_index_end]);
            end_index_yuce = app.Max_Cmd_Point_Selector_.Value;
            cla(app.Planning_path_plot)
            % 1. 当前车辆的估计位置和方向（est_x, est_y, est_yaw）
            quiver(app.Planning_path_plot, ...
                 app.est_x(app.current_index,1), ...
                 app.est_y(app.current_index,1), ...
                 cos(app.est_yaw(app.current_index,1)), sin(app.est_yaw(app.current_index,1)), ...
                 'Color', [1, 0, 0], 'Marker', 'o', 'LineWidth', 1.5, ...
                 'AutoScaleFactor', 5); 
            % 2. 指令轨迹的位置和方向（cmd_x, cmd_y, cmd_yaw）
            if (app.is_cmd_valid)
                quiver(app.Planning_path_plot, ...
                    app.cmd_x(app.current_index,1:end_index_yuce), ...
                    app.cmd_y(app.current_index,1:end_index_yuce), ...
                    cos(app.cmd_yaw(app.current_index,1:end_index_yuce)), sin(app.cmd_yaw(app.current_index,1:end_index_yuce)), ...
                    'Color', [0, 0, 1], 'Marker', '.', 'LineWidth', 1, 'MarkerSize', 14, ...
                    'AutoScaleFactor', 0.1); 
            end
            
            % 3. 其他车辆的位置和方向（other_x, other_y, other_yaw）
            if (app.other_x(app.current_index) ~= 0 || app.other_y(app.current_index) ~= 0) && (app.is_other_valid)
                quiver(app.Planning_path_plot, ...
                     app.other_x(app.current_index), ...
                     app.other_y(app.current_index), ...
                     cos(app.other_yaw(app.current_index)), sin(app.other_yaw(app.current_index)), ...
                     'Color', [1, 0, 1], 'Marker', 'x', 'LineWidth', 1.5, ...
                     'AutoScaleFactor', 0.3); 
            end
            app.plot_track_boundary(app.Planning_path_plot)
           
            % 智能视图管理
            if app.Planning_isFirstUpdate
                % 首次更新：使用自动缩放
                axis_len = 100 * app.plot_size;
                xlim(app.Planning_path_plot, [-axis_len, axis_len] / 2 + app.est_x(app.current_index))
                ylim(app.Planning_path_plot, [-axis_len, axis_len] / 2 + app.est_y(app.current_index))
                app.Planning_isFirstUpdate = false;
            else
                % 非首次更新：保持缩放比例，平移视图
                current_x = app.est_x(app.current_index);
                current_y = app.est_y(app.current_index);
                
                % 计算当前视图的范围（保持缩放比例不变）
                x_range = app.Planning_savedXLim(2) - app.Planning_savedXLim(1);
                y_range = app.Planning_savedYLim(2) - app.Planning_savedYLim(1);
                
                % 以车辆为中心设置新的视图范围（保持原有缩放比例）
                new_xlim = [current_x - x_range/2, current_x + x_range/2];
                new_ylim = [current_y - y_range/2, current_y + y_range/2];
                
                app.Planning_path_plot.XLim = new_xlim;
                app.Planning_path_plot.YLim = new_ylim;
                
                % 更新保存的视图范围
                app.Planning_savedXLim = new_xlim;
                app.Planning_savedYLim = new_ylim;
            end

            
            cla(app.Planning_vel_plot)
            % 绘制规划速度与预测速度点
            plot(app.Planning_vel_plot, ...
                app.time_series(app.current_index), ...
                app.est_velocity(app.current_index,1), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', 'o', 'LineWidth', 1.5);
            plot(app.Planning_vel_plot, ...
                app.time_series(app.current_index:end_index), ...
                app.cmd_velocity(app.current_index,1:end_index_yuce), ...
                'Color', [0, 0, 1], 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 14); 
        
            cla(app.Planning_acc_Plot)
            % 绘制预期速度的微分得到规划加速度
            vel_diff = diff(app.cmd_velocity);
            time_step_diff = diff(app.time_series); % 单位 s
            app.acc_Planning = vel_diff(:,1)./time_step_diff;
            app.acc_Planning = [0;app.acc_Planning]; % 补充起始点
            plot(app.Planning_acc_Plot, app.time_series, app.acc_Planning, ...
                'Color', 'b', 'LineStyle', 'none', ...
                'Marker', '.', 'LineWidth', 1.5, 'MarkerSize', 6);
            % 绘制当前位置的滑移率
            plot(app.Planning_acc_Plot, ...
                    app.time_series(app.current_index), ...
                    app.acc_Planning(app.current_index), ...
                    'Color', [1, 0, 0], 'LineStyle', '-', ...
                    'Marker', 'o', 'LineWidth', 1.5); 

            cla(app.Planning_ggv_plot)
            % 绘制ggv图
            % 在坐标系中绘制当前加速度（ax, ay）的红色圆圈
            if (app.is_observer_acc_valid)
                plot(app.Planning_ggv_plot, ...
                    app.observer_accy(app.current_index), ...
                    app.observer_accx(app.current_index), ...
                    'Color', [1, 0, 0], 'LineStyle', '-', ...
                    'Marker', 'o', 'LineWidth', 1.5);
            end
            % 绘制ggv蓝色散点边界
            if (app.is_acc_valid) 
                % 获取 axmax 和 axenegy（假设为 App 属性，替换为实际控件名）
                % axmax = app.Aw_ref(app.current_index); 
                % axenegy = app.Ae0_ref(app.current_index);
                
                % ax_engine_data % speed ax_drive
                % ggv_file_data  % v_mps ax_max_mps2 ay1_max_mps2
                axenegy = extrap_interp1(app.ae_vmps, app.ae_drive, app.est_velocity(app.current_index));
                axmax = extrap_interp1(app.ax_max_v, app.ax_max_mps2, app.est_velocity(app.current_index));
                aymax = extrap_interp1(app.ax_max_v, app.ay1_max_mps2, app.est_velocity(app.current_index));

                % 椭圆参数
                % a = app.An_ref(app.current_index); % 水平半轴
                a = aymax; % interp
                a08 = 0.8*aymax;
                b = axmax; % 垂直半轴
                b06 = 0.6*axmax;
                b09 = 0.6*axmax;
                xc = 0; % 中心 x 坐标
                yc = 0; % 中心 y 坐标
                
                theta = linspace(0, 2*pi, 500); 
                x = xc + a * cos(theta);
                x06 = xc + a08 * cos(theta);
                x09 = xc + a * cos(theta);
                y = yc + b * sin(theta);
                y06 = yc + b06 * sin(theta);
                y09 = yc + b09 * sin(theta);

                % 计算 x 解
                if axenegy < b
                    if abs(axenegy - yc) <= b
                        discriminant = 1 - ((axenegy - yc)^2) / (b^2);
                        if discriminant >= 0
                            x1 = xc - a * sqrt(discriminant); % 右端点
                            x2 = xc + a * sqrt(discriminant); % 左端点
                            % fprintf('当 y = %.2f 时，x1 = %.4f, x2 = %.4f\n', axenegy, x1, x2);
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b, yc + b);
                    end
                    
                    
                    % 找到满足条件的索引
                    idx = (x >= x1) & (x <= x2) & (y > axenegy);
                    % 将对应 y 值替换为 axenegy
                    y(idx) = axenegy;
                end

                if axenegy < b06
                    % ================================06========================================
                    if abs(axenegy - yc) <= b06
                        discriminant = 1 - ((axenegy - yc)^2) / (b06^2);
                        if discriminant >= 0
                            x106 = xc - a08 * sqrt(discriminant); % 右端点
                            x206 = xc + a08 * sqrt(discriminant); % 左端点
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b06, yc + b06);
                    end
                    
                    
                    % 找到满足条件的索引
                    idx06 = (x06 >= x106) & (x06 <= x206) & (y06 > axenegy);
                    % 将对应 y 值替换为 axenegy
                    y06(idx06) = axenegy;
                end

                if axenegy < b09
                    % ====================================09===================================
                    if abs(axenegy - yc) <= b09
                        discriminant = 1 - ((axenegy - yc)^2) / (b09^2);
                        if discriminant >= 0
                            x109 = xc - a * sqrt(discriminant); % 右端点
                            x209 = xc + a * sqrt(discriminant); % 左端点
                            % fprintf('当 y = %.2f 时，x1 = %.4f, x2 = %.4f\n', axenegy, x1, x2);
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b09, yc + b09);
                    end
                    
                    
                    % 找到满足条件的索引
                    idx09 = (x09 >= x109) & (x09 <= x209) & (y09 > axenegy);
                    % 将对应 y 值替换为 axenegy
                    y09(idx09) = axenegy;
                end
                % plot(app.Planning_ggv_plot, x, y, 'b', 'LineWidth', 2);
                plot(app.Planning_ggv_plot, x06, y06, 'k', 'LineWidth', 2);
                plot(app.Planning_ggv_plot, x09, y09, 'c:', 'LineWidth', 2);
                xlim(app.Planning_ggv_plot,[-a*1.1 a*1.1]); 
                if axenegy < a
                    ylim(app.Planning_ggv_plot,[-b*1.1 axenegy*1.1]);
                else
                    ylim(app.Planning_ggv_plot,[-b*1.1 b*1.1]);
                end
                
                % 原本绘制ggv的方法
                % rec_x = app.Aw_ref(app.current_index);
                % rec_y = min([app.An_ref(app.current_index), app.Ae0_ref(app.current_index)]);
                % rectangle(app.GG_plot, 'Position',[-rec_x,-rec_y,2*rec_x,2*rec_y], 'Curvature', 1, 'EdgeColor', [0, 0, 1], 'LineWidth', 1.5, 'LineStyle', ':')
            end
            
            app.update_info;
        end

        % GGV绘制
        function plot_ggv(app)

            cla(app.GGV_plot)
            plot(app.GGV_plot, ...
                app.observer_accy(app.current_index), ...
                app.observer_accx(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', '*', 'LineWidth', 1);
            
            % 绘制ggv蓝色散点边界
            if (app.is_acc_valid) 
                % 获取 axmax 和 axenegy（假设为 App 属性，替换为实际控件名）
                
                % ax_engine_data % speed ax_drive
                % ggv_file_data  % v_mps ax_max_mps2 ay1_max_mps2
                % Cardata  % v_mps  ax_max_mps2  ay1_max_mps2  % 100708
                axenegy = extrap_interp1(app.ae_vmps, app.ae_drive, app.est_velocity(app.current_index));
                axmax = extrap_interp1(app.ax_max_v, app.ax_max_mps2, app.est_velocity(app.current_index));
                aymax = extrap_interp1(app.ax_max_v, app.ay1_max_mps2, app.est_velocity(app.current_index));
                axmax_cardata = extrap_interp1(app.cardata_v, app.cardata_axmax, app.est_velocity(app.current_index));
                aymax_cardata = extrap_interp1(app.cardata_v, app.cardata_aymax, app.est_velocity(app.current_index));
                
                % 椭圆参数
                a = aymax; % interp
                a08 = 0.8*aymax;
                a_cardata = 0.7*aymax_cardata;
                b = axmax; % 垂直半轴
                b07 = 0.7*axmax;
                b_cardata = axmax_cardata;
                xc = 0; % 中心 x 坐标
                yc = 0; % 中心 y 坐标
                
                theta = linspace(0, 2*pi, 500); 
                x = xc + a * cos(theta);
                x0708 = xc + a08 * cos(theta);
                x0710 = xc + a * cos(theta);
                xCar = xc + a_cardata*cos(theta);

                y = yc + b * sin(theta);
                y0708 = yc + b07 * sin(theta);
                y0710 = yc + b07 * sin(theta);
                yCar = yc + b_cardata*sin(theta);

                
                % ============================ n=1 0708 ========================
                x_dia_0708 = [-0.7*aymax, 0, +0.7*aymax, 0, -0.7*aymax];
                y_dia_0708 = [0, +0.8*axmax, 0, -0.8*axmax, 0];
                
                % ============================ n=1 0709 ========================
                x_dia_0709 = [-0.7*aymax, 0, +0.7*aymax, 0, -0.7*aymax];
                y_dia_0709 = [0, +0.9*axmax, 0, -0.9*axmax, 0];
                
                % ============================ n=1 0710 ========================
                x_dia_0710 = [-0.7*aymax, 0, +0.7*aymax, 0, -0.7*aymax];
                y_dia_0710 = [0, +axmax, 0, -axmax, 0];

                % ============================ n=1 0808 ========================
                x_dia_0808 = [-0.8*aymax, 0, +0.8*aymax, 0, -0.8*aymax];
                y_dia_0808 = [0, +0.8*axmax, 0, -0.8*axmax, 0];

                % ============================ n=1 0908 ========================
                x_dia_0908 = [-0.9*aymax, 0, +0.9*aymax, 0, -0.9*aymax];
                y_dia_0908 = [0, +0.8*axmax, 0, -0.8*axmax, 0];


                % ============================ n=1 1008 ========================
                x_dia_1008 = [-aymax, 0, +aymax, 0, -aymax];
                y_dia_1008 = [0, +0.8*axmax, 0, -0.8*axmax, 0];

                % ============================ n=1 0909 ========================
                x_dia_0909 = [-0.9*aymax, 0, +0.9*aymax, 0, -0.9*aymax];
                y_dia_0909 = [0, +0.9*axmax, 0, -0.9*axmax, 0];

                % ============================ n=1 1010 ========================
                x_dia_1010 = [-aymax, 0, +aymax, 0, -aymax];
                y_dia_1010 = [0, +axmax, 0, -axmax, 0];
                                        
                % ================================0708========================================
                if axenegy < b07
                    if abs(axenegy - yc) <= b07
                        discriminant = 1 - ((axenegy - yc)^2) / (b07^2);
                        if discriminant >= 0
                            x10708 = xc - a08 * sqrt(discriminant); % 右端点
                            x20708 = xc + a08 * sqrt(discriminant); % 左端点
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b07, yc + b07);
                    end
                    
                    % 找到满足条件的索引
                    idx0708 = (x0708 >= x10708) & (x0708 <= x20708) & (y0708 > axenegy);
                    % 将对应 y 值替换为 axenegy
                    y0708(idx0708) = axenegy;
                end


                % ====================================0710===================================
                if axenegy < b07
                    if abs(axenegy - yc) <= b07
                        discriminant = 1 - ((axenegy - yc)^2) / (b07^2);
                        if discriminant >= 0
                            x10710 = xc - a * sqrt(discriminant); % 右端点
                            x20710 = xc + a * sqrt(discriminant); % 左端点
                            % fprintf('当 y = %.2f 时，x1 = %.4f, x2 = %.4f\n', axenegy, x1, x2);
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b07, yc + b07);
                    end
                    
                    % 找到满足条件的索引
                    idx0710 = (x0710 >= x10710) & (x0710 <= x20710) & (y0710 > axenegy);
                    % 将对应 y 值替换为 axenegy
                    y0710(idx0710) = axenegy;
                end

                % =================================cardata=================================
                if axenegy < b_cardata
                    if abs(axenegy - yc) <= b_cardata
                        discriminant = 1 - ((axenegy - yc)^2) / (b_cardata^2);
                        if discriminant >= 0
                            x1Car = xc - a_cardata * sqrt(discriminant); % 右端点
                            x2Car = xc + a_cardata * sqrt(discriminant); % 左端点
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b_cardata, yc + b_cardata);
                    end
                    
                    
                    % 找到满足条件的索引
                    idxCar = (xCar >= x1Car) & (xCar <= x2Car) & (yCar > axenegy);
                    % 将对应 y 值替换为 axenegy
                    yCar(idxCar) = axenegy;
                end

                % ggv_1010 = plot(app.GG_plot, x, y, 'r', 'LineWidth', 2,'DisplayName', 'ggv1010');
                % ggv_1010_dia = plot(app.GG_plot, x_dia, y_dia, 'b', 'LineWidth', 2,'DisplayName', 'ggv1010菱形');
                % ggv_0608 = plot(app.GG_plot, x06, y06, 'k', 'LineWidth', 2,'DisplayName', 'ggv0608');
                % ggv_0708 = plot(app.GG_plot, x07, y07, 'c:', 'LineWidth', 2, 'DisplayName','ggv0708');
                % ggv_cardata = plot(app.GG_plot, xCar, yCar,'g:', 'LineWidth', 2,'DisplayName','cardata100708');

                % cycliny
                ggv_0708 = plot(app.GGV_plot, x0708, y0708, 'r', 'LineWidth', 1.5,'DisplayName', 'ggv0708');
                ggv_0710 = plot(app.GGV_plot, x0710, y0710, 'g', 'LineWidth', 1.5,'DisplayName', 'ggv0710');
                ggv_cardata = plot(app.GGV_plot, xCar, yCar, 'b', 'LineWidth', 1.5,'DisplayName', 'ggvcardata');

                % 菱形
                ggv_0708_dia = plot(app.GGV_plot, x_dia_0708, y_dia_0708,'Color',[255, 165,   0]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0708菱形');
                ggv_0709_dia = plot(app.GGV_plot, x_dia_0709, y_dia_0709,'Color',[255, 255,   0]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0709菱形');
                ggv_0710_dia = plot(app.GGV_plot, x_dia_0710, y_dia_0710,'Color',[  0, 255, 255]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0710菱形');
                ggv_0808_dia = plot(app.GGV_plot, x_dia_0808, y_dia_0808,'Color',[255,   0, 255]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0808菱形');
                ggv_0908_dia = plot(app.GGV_plot, x_dia_0908, y_dia_0908,'Color',[128,   0, 128]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0908菱形');
                ggv_1008_dia = plot(app.GGV_plot, x_dia_1008, y_dia_1008,'Color',[255, 192, 203]/255, 'LineWidth', 1.5,'DisplayName', 'ggv1008菱形');
                ggv_0909_dia = plot(app.GGV_plot, x_dia_0909, y_dia_0909,'Color',[  0, 128,   0]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0909菱形');
                ggv_1010_dia = plot(app.GGV_plot, x_dia_1010, y_dia_1010,'Color',[165,  42,  42]/255, 'LineWidth', 1.5,'DisplayName', 'ggv1010菱形');
                
                legend(app.GGV_plot,[ggv_0708, ggv_0710, ggv_cardata, ...
                    ggv_0708_dia,...
                    ggv_0709_dia,...
                    ggv_0710_dia,...
                    ggv_0808_dia,...
                    ggv_0908_dia,...
                    ggv_1008_dia,...
                    ggv_0909_dia,...
                    ggv_1010_dia],'Interpreter','latex','FontSize',6);
                
                xlim(app.GG_plot,[-a*1.1 a*1.1]); 
                if axenegy < a
                    ylim(app.GG_plot,[-b*1.1 axenegy]);
                else
                    ylim(app.GG_plot,[-b*1.1 b*1.1]);
                end
                
            end
   
            app.update_info;
            
        end
        
        % GGV_HW 绘制
        function plot_ggv_hw(app)

            cla(app.GGV_hw_plot)
            plot(app.GGV_hw_plot, ...
                app.observer_accy(app.current_index), ...
                app.observer_accx(app.current_index), ...
                'Color', [1, 0, 0], 'LineStyle', '-', ...
                'Marker', '*', 'LineWidth', 1);
            
            % 绘制ggv蓝色散点边界
            if (app.is_acc_valid) 
                % 获取 axmax 和 axenegy（假设为 App 属性，替换为实际控件名）
                
                % ax_engine_data % speed ax_drive
                % ggv_file_data  % v_mps ax_max_mps2 ay1_max_mps2
                % Cardata  % v_mps  ax_max_mps2  ay1_max_mps2  % 100708
                axenegy = extrap_interp1(app.ae_vmps, app.ae_drive, app.est_velocity(app.current_index));
                axmax = extrap_interp1(app.ax_max_v, app.ax_max_mps2, app.est_velocity(app.current_index));
                aymax = extrap_interp1(app.ax_max_v, app.ay1_max_mps2, app.est_velocity(app.current_index));
                axmax_cardata = extrap_interp1(app.cardata_v, app.cardata_axmax, app.est_velocity(app.current_index));
                aymax_cardata = extrap_interp1(app.cardata_v, app.cardata_aymax, app.est_velocity(app.current_index));
                
                % 椭圆参数
                a = aymax; % interp
                a08 = 0.8*aymax;
                a_cardata = 0.7*aymax_cardata;
                b = axmax; % 垂直半轴
                b07 = 0.7*axmax;
                b_cardata = axmax_cardata;
                xc = 0; % 中心 x 坐标
                yc = 0; % 中心 y 坐标
                
                theta = linspace(0, 2*pi, 500); 
                x = xc + a * cos(theta);
                x0708 = xc + a08 * cos(theta);
                x0710 = xc + a * cos(theta);
                xCar = xc + a_cardata*cos(theta);

                y = yc + b * sin(theta);
                y0708 = yc + b07 * sin(theta);
                y0710 = yc + b07 * sin(theta);
                yCar = yc + b_cardata*sin(theta);

                
                % ============================ n=1 0708 ========================
                x_dia_0708 = [-0.7*aymax, 0, +0.7*aymax, 0, -0.7*aymax];
                y_dia_0708 = [0, +0.8*axmax, 0, -0.8*axmax, 0];
                
                % ============================ n=1 0709 ========================
                x_dia_0709 = [-0.7*aymax, 0, +0.7*aymax, 0, -0.7*aymax];
                y_dia_0709 = [0, +0.9*axmax, 0, -0.9*axmax, 0];
                
                % ============================ n=1 0710 ========================
                x_dia_0710 = [-0.7*aymax, 0, +0.7*aymax, 0, -0.7*aymax];
                y_dia_0710 = [0, +axmax, 0, -axmax, 0];

                % ============================ n=1 0808 ========================
                x_dia_0808 = [-0.8*aymax, 0, +0.8*aymax, 0, -0.8*aymax];
                y_dia_0808 = [0, +0.8*axmax, 0, -0.8*axmax, 0];

                % ============================ n=1 0908 ========================
                x_dia_0908 = [-0.9*aymax, 0, +0.9*aymax, 0, -0.9*aymax];
                y_dia_0908 = [0, +0.8*axmax, 0, -0.8*axmax, 0];


                % ============================ n=1 1008 ========================
                x_dia_1008 = [-aymax, 0, +aymax, 0, -aymax];
                y_dia_1008 = [0, +0.8*axmax, 0, -0.8*axmax, 0];

                % ============================ n=1 0909 ========================
                x_dia_0909 = [-0.9*aymax, 0, +0.9*aymax, 0, -0.9*aymax];
                y_dia_0909 = [0, +0.9*axmax, 0, -0.9*axmax, 0];

                % ============================ n=1 1010 ========================
                x_dia_1010 = [-aymax, 0, +aymax, 0, -aymax];
                y_dia_1010 = [0, +axmax, 0, -axmax, 0];
                                        
                % ================================0708========================================
                if axenegy < b07
                    if abs(axenegy - yc) <= b07
                        discriminant = 1 - ((axenegy - yc)^2) / (b07^2);
                        if discriminant >= 0
                            x10708 = xc - a08 * sqrt(discriminant); % 右端点
                            x20708 = xc + a08 * sqrt(discriminant); % 左端点
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b07, yc + b07);
                    end
                    
                    % 找到满足条件的索引
                    idx0708 = (x0708 >= x10708) & (x0708 <= x20708) & (y0708 > axenegy);
                    % 将对应 y 值替换为 axenegy
                    y0708(idx0708) = axenegy;
                end


                % ====================================0710===================================
                if axenegy < b07
                    if abs(axenegy - yc) <= b07
                        discriminant = 1 - ((axenegy - yc)^2) / (b07^2);
                        if discriminant >= 0
                            x10710 = xc - a * sqrt(discriminant); % 右端点
                            x20710 = xc + a * sqrt(discriminant); % 左端点
                            % fprintf('当 y = %.2f 时，x1 = %.4f, x2 = %.4f\n', axenegy, x1, x2);
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b07, yc + b07);
                    end
                    
                    % 找到满足条件的索引
                    idx0710 = (x0710 >= x10710) & (x0710 <= x20710) & (y0710 > axenegy);
                    % 将对应 y 值替换为 axenegy
                    y0710(idx0710) = axenegy;
                end

                % =================================cardata=================================
                if axenegy < b_cardata
                    if abs(axenegy - yc) <= b_cardata
                        discriminant = 1 - ((axenegy - yc)^2) / (b_cardata^2);
                        if discriminant >= 0
                            x1Car = xc - a_cardata * sqrt(discriminant); % 右端点
                            x2Car = xc + a_cardata * sqrt(discriminant); % 左端点
                        else
                            fprintf('无实数解，y = %.2f 超出ggv椭圆范围\n', axenegy);
                        end
                    else
                        fprintf('y = %.2f 超出ggv椭圆范围 [%.2f, %.2f]\n', axenegy, yc - b_cardata, yc + b_cardata);
                    end
                    
                    
                    % 找到满足条件的索引
                    idxCar = (xCar >= x1Car) & (xCar <= x2Car) & (yCar > axenegy);
                    % 将对应 y 值替换为 axenegy
                    yCar(idxCar) = axenegy;
                end

                % ggv_1010 = plot(app.GG_plot, x, y, 'r', 'LineWidth', 2,'DisplayName', 'ggv1010');
                % ggv_1010_dia = plot(app.GG_plot, x_dia, y_dia, 'b', 'LineWidth', 2,'DisplayName', 'ggv1010菱形');
                % ggv_0608 = plot(app.GG_plot, x06, y06, 'k', 'LineWidth', 2,'DisplayName', 'ggv0608');
                % ggv_0708 = plot(app.GG_plot, x07, y07, 'c:', 'LineWidth', 2, 'DisplayName','ggv0708');
                % ggv_cardata = plot(app.GG_plot, xCar, yCar,'g:', 'LineWidth', 2,'DisplayName','cardata100708');

                % cycliny
                ggv_0708 = plot(app.GGV_hw_plot, x0708, y0708, 'r', 'LineWidth', 1.5,'DisplayName', 'ggv0708');
                ggv_0710 = plot(app.GGV_hw_plot, x0710, y0710, 'g', 'LineWidth', 1.5,'DisplayName', 'ggv0710');
                ggv_cardata = plot(app.GGV_hw_plot, xCar, yCar, 'b', 'LineWidth', 1.5,'DisplayName', 'ggvcardata');

                % 菱形
                ggv_0708_dia = plot(app.GGV_hw_plot, x_dia_0708, y_dia_0708,'Color',[255, 165,   0]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0708菱形');
                ggv_0709_dia = plot(app.GGV_hw_plot, x_dia_0709, y_dia_0709,'Color',[255, 255,   0]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0709菱形');
                ggv_0710_dia = plot(app.GGV_hw_plot, x_dia_0710, y_dia_0710,'Color',[  0, 255, 255]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0710菱形');
                ggv_0808_dia = plot(app.GGV_hw_plot, x_dia_0808, y_dia_0808,'Color',[255,   0, 255]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0808菱形');
                ggv_0908_dia = plot(app.GGV_hw_plot, x_dia_0908, y_dia_0908,'Color',[128,   0, 128]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0908菱形');
                ggv_1008_dia = plot(app.GGV_hw_plot, x_dia_1008, y_dia_1008,'Color',[255, 192, 203]/255, 'LineWidth', 1.5,'DisplayName', 'ggv1008菱形');
                ggv_0909_dia = plot(app.GGV_hw_plot, x_dia_0909, y_dia_0909,'Color',[  0, 128,   0]/255, 'LineWidth', 1.5,'DisplayName', 'ggv0909菱形');
                ggv_1010_dia = plot(app.GGV_hw_plot, x_dia_1010, y_dia_1010,'Color',[165,  42,  42]/255, 'LineWidth', 1.5,'DisplayName', 'ggv1010菱形');
                
                legend(app.GGV_hw_plot,[ggv_0708, ggv_0710, ggv_cardata, ...
                    ggv_0708_dia,...
                    ggv_0709_dia,...
                    ggv_0710_dia,...
                    ggv_0808_dia,...
                    ggv_0908_dia,...
                    ggv_1008_dia,...
                    ggv_0909_dia,...
                    ggv_1010_dia],'Interpreter','latex','FontSize',6);
                
                xlim(app.GG_plot,[-a*1.1 a*1.1]); 
                if axenegy < a
                    ylim(app.GG_plot,[-b*1.1 axenegy]);
                else
                    ylim(app.GG_plot,[-b*1.1 b*1.1]);
                end
                
            end
   
            app.update_info;
            
        end


        % 更新信息参数
        function update_info(app)
            app.Text_self_pos.Value = app.parse_self_pos;  % 获取赛车当前位置所在赛道的字符串
            app.Text_state.Value = app.parse_state_machine; % 更新状态机
            % 显示当前时间戳(ns)
            app.Label_Time_stamp.Text = sprintf('Time_stamp: %sns', num2str(app.time_stamp(app.current_index)));
            % 更新车辆运行时间
            app.Text_time.Value = '';
            app.time_now = app.time_series(app.current_index);
            app.time_min = floor(app.time_now/60);  % min
            app.time_s = floor(app.time_now - app.time_min * 60); % s
            app.time_ms = round(app.time_now - app.time_min * 60 - app.time_s); %  ms
            app.Text_time.Value = sprintf('%sm %ss %sms', num2str(app.time_min), num2str(app.time_s),num2str(app.time_ms)); % 当前运行时间xxminxxsxxms

            app.Text_op_pos_flag.Value = app.parse_num_value("op_path_flag"); % 将路径优化标志位转换为数字
            app.Text_op_vel_flag.Value = app.parse_num_value("op_vel_flag"); % 将速度优化标志位转换为数字
            % 将速度限制值和百分比格式化为一个字符串
            app.Text_vel_limit.Value = sprintf('%sm/s %s%%', app.parse_num_value("rc_speed_limit"), app.parse_num_value("rc_speed_percent"));

            % 将侧向加速度误差、航向角误差、速度误差转换为字符串赋值
            app.Text_lat_error.Value = app.parse_num_value("lateral_error");
            app.Text_yaw_error.Value = app.parse_num_value("yaw_error");
            app.Text_vel_error.Value = app.parse_num_value("speed_error");
            % 获取当前状态下优化求解所需的运行时间
            app.Text_step_time.Value = num2str(app.step_elapsed_millisec(app.current_index));
            % 更新当前位置的s距离和速度velocity
            app.Text_s.Value = num2str(app.est_s(app.current_index));
            app.Text_vel.Value = num2str(app.est_velocity(app.current_index));
        end
        

        % function check_runtime(app)
        %     version_str = version('-release');
        %     if (str2double(version_str(1:4)) < 2024)
        %         uialert(app.A2RL_UIFigure_, sprintf("此 MATLAB 版本 %s 低于本 app 开发时所用的版本，可能会导致异常行为，请谨慎使用", version_str), "警告", "Icon", "warning", "Interpreter", "html")
        %     end
        % end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % 初始化组件
            app.disable_components;
            app.tab_index = app.Tab_Main.Title;

            % 让 App 居中
            app.make_app_center;
            
            % 读取赛道文件
            app.read_track_file;

            app.read_ggv_boundary;
            
            % % 检查运行环境
            % app.check_runtime;

        end

        % Value changed function: Curr_Idx_Selector_
        function Curr_Idx_Selector_ValueChanged(app, event)
            % 当前索引组件改变后的赋值操作
            value = app.Curr_Idx_Selector_.Value;
            app.current_index = value;
            app.Curr_Idx_Slider_.Value = value;
            app.Lap_Selector_.Value = app.csv_data.lap_count(app.current_index)+1;  % 滑动滑块时更新一下当前圈数
            % print('lap_count:%d\n',app.lap_count);
            app.update_select_plot;
            
        end

        % Value changing function: Curr_Idx_Selector_
        function Curr_Idx_Selector_ValueChanging(app, event)
            % 
            changingValue = event.Value;
            app.Curr_Idx_Slider_.Value = changingValue;
        end

        % Value changed function: Curr_Idx_Slider_
        function Curr_Idx_Slider_ValueChanged(app, event)
            % 滑块修改索引组件改变索引后的赋值操作
            if app.lap_s_change_flag == 1
                value = app.lap_s_idx;
                app.lap_s_change_flag = 0;
            else
                value = round(app.Curr_Idx_Slider_.Value);
            end
            app.current_index = value;
            app.Curr_Idx_Selector_.Value = value; % 当前索引值修改
            app.Curr_Idx_Slider_.Value = value; % 当前索引值滑块修改
            app.update_select_plot;
            app.update_info;
        end

        % Value changing function: Curr_Idx_Slider_
        function Curr_Idx_Slider_ValueChanging(app, event)
            % 滑块变化过程中索引的赋值操作
            changingValue = round(event.Value);
            app.Curr_Idx_Selector_.Value = changingValue;
        end

        % Button pushed function: Read_File_Button_
        function Read_File_Button_Pushed(app, event)
            % 读取log文件按钮触发事件
            [file, path] = uigetfile({'*.csv'});
            
            if num2str(file) == '0'
                return
            end

            app.File_Label.Text = ['当前文件：',  file];
            drawnow
            app.csv_data_filename = file;
            app.csv_data_filepath = path;
            
            % 读取数据
            app.read_data_file;
            focus(app.A2RL_UIFigure_);
            if (app.is_valid_file_read)
                app.enable_components;
                % 设置组件 Value，再设置 Limits
                app.init_index_value;
                app.init_index_limit;
    
                % 更新当前展示的 tab
                app.update_select_plot;
            end       
        end

        % Value changed function: Max_Cmd_Point_Selector_
        function Max_Cmd_Point_Selector_ValueChanged(app, event)
            % TODO: 要看在哪个 tab
            % 预测绘制点数增减组件
            app.update_select_plot;
        end

        % Key press function: A2RL_UIFigure_
        function A2RL_UIFigure_KeyPress(app, event)
            % 键盘操作所有可调整索引控件的增减
            key = event.Key;
            % 当选中对应组件，每触发一次键盘上的上下箭头按键，就出发一次索引的增减
            if isequal(key, 'leftarrow') && isequal(app.Curr_Idx_Selector_.Enable, 'on') && app.Curr_Idx_Selector_.Value + app.Curr_Idx_Selector_.Step <= app.Curr_Idx_Selector_.Limits(2)
                app.Curr_Idx_Selector_.Value = app.Curr_Idx_Selector_.Value + app.Curr_Idx_Selector_.Step;
                app.Curr_Idx_Selector_ValueChanged;
                return
            end

            if isequal(key, 'rightarrow') && isequal(app.Curr_Idx_Selector_.Enable, 'on') && app.Curr_Idx_Selector_.Value - app.Curr_Idx_Selector_.Step >= app.Curr_Idx_Selector_.Limits(1)
                app.Curr_Idx_Selector_.Value = app.Curr_Idx_Selector_.Value - app.Curr_Idx_Selector_.Step;
                app.Curr_Idx_Selector_ValueChanged;
                return
            end
        end

        % Value changed function: Lap_Selector_
        function Lap_Selector_ValueChanged(app, event)
            % 圈数选择组件
            % 找到所有lap_count变化的索引（变化发生在的下一行索引）
            changes = find(diff(app.lap_count) ~= 0) + 1;
            % 包括第一个索引（lap_count=0的起始点）
            changes = [1; changes];
            % 获取变化处对应的lap_count值
            new_lap_counts = app.lap_count(changes);
            % 查找目标lap_count的第一个索引
            matching_index = changes(find(new_lap_counts == app.Lap_Selector_.Value-1, 1, 'first'));
            app.Curr_Idx_Slider_.Value = matching_index;
            app.Curr_Idx_Slider_ValueChanged;
            app.update_select_plot; 
        end

        % Value changed function: Curr_Step_DropDown_
        function Curr_Step_DropDown_ValueChanged(app, event)
            % 步长选择组件
            value = app.Curr_Step_DropDown_.Value;
            app.Curr_Idx_Selector_.Step = str2double(value);
        end

        % Value changed function: Plot_View_Size_DropDown_
        function Plot_View_Size_DropDown_ValueChanged(app, event)
            % 视野大小选择组件
            value = app.Plot_View_Size_DropDown_.Value;
            if value == "小"
                app.plot_size = 0.25;
            elseif value == "正常"
                app.plot_size = 1;
            elseif value == "大"   
                app.plot_size = 2;
            end
            app.update_select_plot;
        end

        % Selection change function: Tab_Group_
        function Tab_Group_SelectionChanged(app, event)
            % tab视图选择组件
            app.tab_index = app.Tab_Group_.SelectedTab.Title;
            app.update_select_plot;
        end

        % Button pushed function: pitButton
        function pitButtonPushed(app, event)
            % button事件：含有pit的路径边界显示
            app.pit_show_flag = 1;
            app.update_plot_each_lap;
            app.pitButton.Enable = "off";
            app.nopitButton.Enable = "on";
        end

        % Button pushed function: nopitButton
        function nopitButtonPushed(app, event)
                % button事件：含有pit的路径边界显示
                app.pit_show_flag = 0;
                app.update_plot_each_lap;
                app.pitButton.Enable = "on";
                app.nopitButton.Enable = "off";
        end

        % Value changed function: Text_s
        function Text_sValueChanged(app, event)
            value = app.Text_s.Value;
            value = str2double(value);
            % writematrix(app.cmd_x, 'output_cmd_x.csv');
            if value >= 0 && value <= max(app.est_s(app.lap_count == app.Lap_Selector_.Value-1)) 
                subset_indices = find(app.lap_count == app.Lap_Selector_.Value-1);
                [~, local_idx] = min(abs(app.est_s(subset_indices) - value));
                app.lap_s_idx = subset_indices(local_idx); % 如果需要原始数组中的全局索引!!!
                app.lap_s_change_flag = 1;
                app.Curr_Idx_Slider_.Value = app.lap_s_idx;
                app.Curr_Idx_Slider_ValueChanged;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.A2RL_UIFigure_ = uifigure('Visible', 'off');
            app.A2RL_UIFigure_.Name = 'A2RL 赛车规划可视化分析';
            app.A2RL_UIFigure_.Icon = fullfile(pathToMLAPP, 'racing-car.png');
            app.A2RL_UIFigure_.KeyPressFcn = createCallbackFcn(app, @A2RL_UIFigure_KeyPress, true);
            app.MainGrid = uigridlayout(app.A2RL_UIFigure_);
            app.LeftPanel = uipanel(app.MainGrid);
            app.Left_Main_Grid = uigridlayout(app.LeftPanel);
            app.Title_Grid = uigridlayout(app.Left_Main_Grid);
            app.A2RLLabel = uilabel(app.Title_Grid);
            app.A2RLLabel.Text = 'A2RL 赛车规划组可视化分析软件';
            app.AuthorLabel = uilabel(app.Title_Grid);
            app.AuthorLabel.Text = 'Code by CptKK';
            app.Myicon = uiimage(app.Title_Grid);
            app.Myicon.ImageSource = fullfile(pathToMLAPP, 'flyeagle_logo.png');
            app.ReadFile_Grid = uigridlayout(app.Left_Main_Grid);
            app.Read_File_Button_ = uibutton(app.ReadFile_Grid, 'push');
            app.Read_File_Button_.ButtonPushedFcn = createCallbackFcn(app, @Read_File_Button_Pushed, true);
            app.Read_File_Button_.Text = '读取';
            app.File_Label = uilabel(app.ReadFile_Grid);
            app.File_Label.Text = '当前文件：等待读取……';
            app.Label_Time_stamp = uilabel(app.ReadFile_Grid);
            app.Label_Time_stamp.Text = 'Time_stamp: ';
            app.Selector_Grid = uigridlayout(app.Left_Main_Grid);
            app.Label_14 = uilabel(app.Selector_Grid);
            app.Label_14.Text = '当前索引';
            app.Curr_Idx_Selector_ = uispinner(app.Selector_Grid);
            app.Curr_Idx_Selector_.Step = 10;
            app.Curr_Idx_Selector_.ValueChangingFcn = createCallbackFcn(app, @Curr_Idx_Selector_ValueChanging, true);
            app.Curr_Idx_Selector_.Limits = [1 Inf];
            app.Curr_Idx_Selector_.RoundFractionalValues = 'on';
            app.Curr_Idx_Selector_.ValueChangedFcn = createCallbackFcn(app, @Curr_Idx_Selector_ValueChanged, true);
            app.Curr_Idx_Selector_.Value = 1;
            app.Label_17 = uilabel(app.Selector_Grid);
            app.Label_17.Text = '步长';
            app.Curr_Step_DropDown_ = uidropdown(app.Selector_Grid);
            app.Curr_Step_DropDown_.Items = {'1', '10', '100'};
            app.Curr_Step_DropDown_.ValueChangedFcn = createCallbackFcn(app, @Curr_Step_DropDown_ValueChanged, true);
            app.Curr_Step_DropDown_.Value = '10';
            app.Curr_Idx_Slider_ = uislider(app.Selector_Grid);
            app.Curr_Idx_Slider_.Limits = [1 101];
            app.Curr_Idx_Slider_.ValueChangedFcn = createCallbackFcn(app, @Curr_Idx_Slider_ValueChanged, true);
            app.Curr_Idx_Slider_.ValueChangingFcn = createCallbackFcn(app, @Curr_Idx_Slider_ValueChanging, true);
            app.Curr_Idx_Slider_.Value = 1;
            app.Label_2 = uilabel(app.Selector_Grid);
            app.Label_2.Text = '绘制点数';
            app.Max_Cmd_Point_Selector_ = uispinner(app.Selector_Grid);
            app.Max_Cmd_Point_Selector_.Limits = [0 Inf];
            app.Max_Cmd_Point_Selector_.RoundFractionalValues = 'on';
            app.Max_Cmd_Point_Selector_.ValueChangedFcn = createCallbackFcn(app, @Max_Cmd_Point_Selector_ValueChanged, true);
            app.Max_Cmd_Point_Selector_.Value = 10;
            app.Label_18 = uilabel(app.Selector_Grid);
            app.Label_18.Text = '轨迹视野';
            app.Plot_View_Size_DropDown_ = uidropdown(app.Selector_Grid);
            app.Plot_View_Size_DropDown_.Items = {'小', '正常', '大'};
            app.Plot_View_Size_DropDown_.ValueChangedFcn = createCallbackFcn(app, @Plot_View_Size_DropDown_ValueChanged, true);
            app.Plot_View_Size_DropDown_.Value = '正常';
            app.Text_Grid = uigridlayout(app.Left_Main_Grid);
            app.Label_6 = uilabel(app.Text_Grid);
            app.Label_6.Text = '所处位置';
            app.Text_self_pos = uieditfield(app.Text_Grid, 'text');
            app.Text_self_pos.Editable = 'off';
            app.Label_3 = uilabel(app.Text_Grid);
            app.Label_3.Text = {'状态机'; '状态'};
            app.Text_state = uieditfield(app.Text_Grid, 'text');
            app.Text_state.Editable = 'off';
            app.Label_8 = uilabel(app.Text_Grid);
            app.Label_8.Text = {'RC'; '速度限制'};
            app.Text_vel_limit = uieditfield(app.Text_Grid, 'text');
            app.Text_vel_limit.Editable = 'off';
            app.Label_7 = uilabel(app.Text_Grid);
            app.Label_7.Text = {'当前运行'; '时间(s)'};
            app.Text_time = uieditfield(app.Text_Grid, 'text');
            app.Text_time.Editable = 'off';
            app.Label_4 = uilabel(app.Text_Grid);
            app.Label_4.Text = {'位置优化'; '结果'};
            app.Text_op_pos_flag = uieditfield(app.Text_Grid, 'text');
            app.Text_op_pos_flag.Editable = 'off';
            app.Label_5 = uilabel(app.Text_Grid);
            app.Label_5.Text = {'速度优化'; '结果'};
            app.Text_op_vel_flag = uieditfield(app.Text_Grid, 'text');
            app.Text_op_vel_flag.Editable = 'off';
            app.Label_9 = uilabel(app.Text_Grid);
            app.Label_9.Text = {'横向误差'; '(m)'};
            app.Text_lat_error = uieditfield(app.Text_Grid, 'text');
            app.Text_lat_error.Editable = 'off';
            app.Label_10 = uilabel(app.Text_Grid);
            app.Label_10.Text = {'航向误差'; '(deg)'};
            app.Text_yaw_error = uieditfield(app.Text_Grid, 'text');
            app.Text_yaw_error.Editable = 'off';
            app.Label_11 = uilabel(app.Text_Grid);
            app.Label_11.Text = {'速度误差'; '(m/s)'};
            app.Text_vel_error = uieditfield(app.Text_Grid, 'text');
            app.Text_vel_error.Editable = 'off';
            app.Label_12 = uilabel(app.Text_Grid);
            app.Label_12.Text = {'规划时间'; '(ms)'};
            app.Text_step_time = uieditfield(app.Text_Grid, 'text');
            app.Text_step_time.Editable = 'off';
            app.vmsEditFieldLabel = uilabel(app.Text_Grid);
            app.vmsEditFieldLabel.Text = '速度v(m/s)';
            app.Text_vel = uieditfield(app.Text_Grid, 'text');
            app.smEditFieldLabel = uilabel(app.Text_Grid);
            app.smEditFieldLabel.Text = '位置s(m)';
            app.Text_s = uieditfield(app.Text_Grid, 'text');
            app.Text_s.ValueChangedFcn = createCallbackFcn(app, @Text_sValueChanged, true);
            app.RightPanel = uipanel(app.MainGrid);
            app.Right_Main_Grid = uigridlayout(app.RightPanel);
            app.Tab_Group_ = uitabgroup(app.Right_Main_Grid);
            app.Tab_Group_.SelectionChangedFcn = createCallbackFcn(app, @Tab_Group_SelectionChanged, true);
            app.Tab_Main = uitab(app.Tab_Group_);
            app.Tab_Main.Title = '关键图';
            app.Tab_Main_Grid = uigridlayout(app.Tab_Main);
            app.GG_plot = uiaxes(app.Tab_Main_Grid);
            title(app.GG_plot, 'GG图')
            xlabel(app.GG_plot, 'Ay(m/s^2)')
            ylabel(app.GG_plot, 'Ax (m/s^2)')
            zlabel(app.GG_plot, 'Z')
            app.Yaw_plot = uiaxes(app.Tab_Main_Grid);
            title(app.Yaw_plot, '航向')
            xlabel(app.Yaw_plot, 't (s)')
            ylabel(app.Yaw_plot, 'Yaw (deg)')
            zlabel(app.Yaw_plot, 'Z')
            app.Traj_plot = uiaxes(app.Tab_Main_Grid);
            title(app.Traj_plot, '轨迹')
            xlabel(app.Traj_plot, 'x (m)')
            ylabel(app.Traj_plot, 'y (m)')
            zlabel(app.Traj_plot, 'Z')
            app.Vel_plot = uiaxes(app.Tab_Main_Grid);
            title(app.Vel_plot, '速度')
            xlabel(app.Vel_plot, 't (s)')
            ylabel(app.Vel_plot, 'V (m/s)')
            zlabel(app.Vel_plot, 'Z')
            app.Tab_Each_Lap = uitab(app.Tab_Group_);
            app.Tab_Each_Lap.Title = '每圈轨迹';
            app.Tab_Each_Lap_Grid = uigridlayout(app.Tab_Each_Lap);
            app.Each_lap_plot = uiaxes(app.Tab_Each_Lap_Grid);
            title(app.Each_lap_plot, '每圈轨迹')
            xlabel(app.Each_lap_plot, 'x (m)')
            ylabel(app.Each_lap_plot, 'y (m)')
            zlabel(app.Each_lap_plot, 'Z')
            app.Label_16 = uilabel(app.Tab_Each_Lap_Grid);
            app.Label_16.Text = '圈数';
            app.Lap_Selector_ = uispinner(app.Tab_Each_Lap_Grid);
            app.Lap_Selector_.Limits = [1 Inf];
            app.Lap_Selector_.RoundFractionalValues = 'on';
            app.Lap_Selector_.ValueDisplayFormat = '%d';
            app.Lap_Selector_.ValueChangedFcn = createCallbackFcn(app, @Lap_Selector_ValueChanged, true);
            app.Lap_Selector_.Value = 1;
            app.pitButton = uibutton(app.Tab_Each_Lap_Grid, 'push');
            app.pitButton.ButtonPushedFcn = createCallbackFcn(app, @pitButtonPushed, true);
            app.pitButton.Text = '含pit';
            app.nopitButton = uibutton(app.Tab_Each_Lap_Grid, 'push');
            app.nopitButton.ButtonPushedFcn = createCallbackFcn(app, @nopitButtonPushed, true);
            app.nopitButton.Text = '不含pit';
            app.Label_lap_time = uilabel(app.Tab_Each_Lap_Grid);
            app.Label_lap_time.Text = '当前圈速:';
            app.Tab_Runtime = uitab(app.Tab_Group_);
            app.Tab_Runtime.Title = '规划时间';
            app.Tab_Runtime_Grid = uigridlayout(app.Tab_Runtime);
            app.steptime_plot = uiaxes(app.Tab_Runtime_Grid);
            title(app.steptime_plot, '规划时间')
            xlabel(app.steptime_plot, 't (s)')
            ylabel(app.steptime_plot, 'runtime (ms)')
            zlabel(app.steptime_plot, 'Z')
            app.Tab_Acc = uitab(app.Tab_Group_);
            app.Tab_Acc.Title = '加速度';
            app.Tab_Acc_Grid = uigridlayout(app.Tab_Acc);
            app.Acc_X_plot = uiaxes(app.Tab_Acc_Grid);
            title(app.Acc_X_plot, 'x方向加速度')
            xlabel(app.Acc_X_plot, '索引')
            ylabel(app.Acc_X_plot, 'a_x (m/s^2)')
            zlabel(app.Acc_X_plot, 'Z')
            app.Acc_Y_plot = uiaxes(app.Tab_Acc_Grid);
            title(app.Acc_Y_plot, 'y方向加速度')
            xlabel(app.Acc_Y_plot, '索引')
            ylabel(app.Acc_Y_plot, 'a_y (m/s^2) ')
            zlabel(app.Acc_Y_plot, 'Z')
            app.Tab_Err = uitab(app.Tab_Group_);
            app.Tab_Err.Title = '误差';
            app.Tab_Err_Grid = uigridlayout(app.Tab_Err);
            app.Vel_err_plot = uiaxes(app.Tab_Err_Grid);
            title(app.Vel_err_plot, '速度误差')
            xlabel(app.Vel_err_plot, 't (s)')
            ylabel(app.Vel_err_plot, 'e_V (m/s)')
            zlabel(app.Vel_err_plot, 'Z')
            app.Lat_err_plot = uiaxes(app.Tab_Err_Grid);
            title(app.Lat_err_plot, '横向误差')
            xlabel(app.Lat_err_plot, 't (s)')
            ylabel(app.Lat_err_plot, 'e_L (m)')
            zlabel(app.Lat_err_plot, 'Z')
            app.Yaw_err_plot = uiaxes(app.Tab_Err_Grid);
            title(app.Yaw_err_plot, '航向误差')
            xlabel(app.Yaw_err_plot, 't (s)')
            ylabel(app.Yaw_err_plot, 'e_H (deg)')
            zlabel(app.Yaw_err_plot, 'Z')
            app.Tab_Tyre_Temp = uitab(app.Tab_Group_);
            app.Tab_Tyre_Temp.Title = '轮胎温度';
            app.Tab_Tyre_Temp_Grid = uigridlayout(app.Tab_Tyre_Temp);
            app.FL_Tyre_plot = uiaxes(app.Tab_Tyre_Temp_Grid);
            title(app.FL_Tyre_plot, '左前胎温')
            xlabel(app.FL_Tyre_plot, 't (s)')
            ylabel(app.FL_Tyre_plot, 'Temp (℃)')
            zlabel(app.FL_Tyre_plot, 'Z')
            app.FR_Tyre_plot = uiaxes(app.Tab_Tyre_Temp_Grid);
            title(app.FR_Tyre_plot, '右前胎温')
            xlabel(app.FR_Tyre_plot, 't (s)')
            ylabel(app.FR_Tyre_plot, 'Temp (℃)')
            zlabel(app.FR_Tyre_plot, 'Z')
            app.RR_Tyre_plot = uiaxes(app.Tab_Tyre_Temp_Grid);
            title(app.RR_Tyre_plot, '右后胎温')
            xlabel(app.RR_Tyre_plot, 't (s)')
            ylabel(app.RR_Tyre_plot, 'Temp (℃)')
            zlabel(app.RR_Tyre_plot, 'Z')
            app.RL_Tyre_plot = uiaxes(app.Tab_Tyre_Temp_Grid);
            title(app.RL_Tyre_plot, '左后胎温')
            xlabel(app.RL_Tyre_plot, 't (s)')
            ylabel(app.RL_Tyre_plot, 'Temp (℃)')
            zlabel(app.RL_Tyre_plot, 'Z')
            app.fontstylecolorredfontLabel = uilabel(app.Tab_Tyre_Temp_Grid);
            app.fontstylecolorredfontLabel.Interpreter = 'html';
            app.fontstylecolorredfontLabel.Text = '注：<font style=''color:red;''>红色</font>为中心胎温，<font style=''color:green;''>绿色</font>为内部胎温，<font style=''color:blue;''>蓝色</font>为外部胎温';
            app.Tab_Slip_Angle = uitab(app.Tab_Group_);
            app.Tab_Slip_Angle.Title = '滑移角';
            app.Tab_Slip_Angle_Grid = uigridlayout(app.Tab_Slip_Angle);
            app.FL_Slip_Angle = uiaxes(app.Tab_Slip_Angle_Grid);
            title(app.FL_Slip_Angle, '左前滑移角')
            xlabel(app.FL_Slip_Angle, 't (s)')
            ylabel(app.FL_Slip_Angle, '角度 (deg)')
            zlabel(app.FL_Slip_Angle, 'Z')
            app.FR_Slip_Angle = uiaxes(app.Tab_Slip_Angle_Grid);
            title(app.FR_Slip_Angle, '右前滑移角')
            xlabel(app.FR_Slip_Angle, 't (s)')
            ylabel(app.FR_Slip_Angle, '角度 (deg)')
            zlabel(app.FR_Slip_Angle, 'Z')
            app.RL_Slip_Angle = uiaxes(app.Tab_Slip_Angle_Grid);
            title(app.RL_Slip_Angle, '左后滑移角')
            xlabel(app.RL_Slip_Angle, 't (s)')
            ylabel(app.RL_Slip_Angle, '角度 (deg)')
            zlabel(app.RL_Slip_Angle, 'Z')
            app.RR_Slip_Angle = uiaxes(app.Tab_Slip_Angle_Grid);
            title(app.RR_Slip_Angle, '右后滑移角')
            xlabel(app.RR_Slip_Angle, 't (s)')
            ylabel(app.RR_Slip_Angle, '角度 (deg)')
            zlabel(app.RR_Slip_Angle, 'Z')
            app.Tab_Slip_Ratio = uitab(app.Tab_Group_);
            app.Tab_Slip_Ratio.Title = '滑移率';
            app.Tab_Slip_Ratio_Grid = uigridlayout(app.Tab_Slip_Ratio);
            app.FL_Slip_Ratio = uiaxes(app.Tab_Slip_Ratio_Grid);
            title(app.FL_Slip_Ratio, '左前滑移率')
            xlabel(app.FL_Slip_Ratio, 't (s)')
            ylabel(app.FL_Slip_Ratio, '值')
            zlabel(app.FL_Slip_Ratio, 'Z')
            app.FR_Slip_Ratio = uiaxes(app.Tab_Slip_Ratio_Grid);
            title(app.FR_Slip_Ratio, '右前滑移率')
            xlabel(app.FR_Slip_Ratio, 't (s)')
            ylabel(app.FR_Slip_Ratio, '值')
            zlabel(app.FR_Slip_Ratio, 'Z')
            app.RL_Slip_Ratio = uiaxes(app.Tab_Slip_Ratio_Grid);
            title(app.RL_Slip_Ratio, '左后滑移率')
            xlabel(app.RL_Slip_Ratio, 't (s)')
            ylabel(app.RL_Slip_Ratio, '值')
            zlabel(app.RL_Slip_Ratio, 'Z')
            app.RR_Slip_Ratio = uiaxes(app.Tab_Slip_Ratio_Grid);
            title(app.RR_Slip_Ratio, '右后滑移率')
            xlabel(app.RR_Slip_Ratio, 't (s)')
            ylabel(app.RR_Slip_Ratio, '值')
            zlabel(app.RR_Slip_Ratio, 'Z')
            app.Tab_Old_Slip = uitab(app.Tab_Group_);
            app.Tab_Old_Slip.Title = '旧滑移';
            app.Tab_Old_Slip_Grid = uigridlayout(app.Tab_Old_Slip);
            app.Old_Front_Slip_Angle = uiaxes(app.Tab_Old_Slip_Grid);
            title(app.Old_Front_Slip_Angle, '旧计算方法的前轮滑移角')
            xlabel(app.Old_Front_Slip_Angle, 't (s)')
            ylabel(app.Old_Front_Slip_Angle, '角度 (deg)')
            zlabel(app.Old_Front_Slip_Angle, 'Z')
            app.Old_Rear_Slip_Angle = uiaxes(app.Tab_Old_Slip_Grid);
            title(app.Old_Rear_Slip_Angle, '旧计算方法的后轮滑移角')
            xlabel(app.Old_Rear_Slip_Angle, 't (s)')
            ylabel(app.Old_Rear_Slip_Angle, '角度 (deg)')
            zlabel(app.Old_Rear_Slip_Angle, 'Z')
            app.Old_Front_Slip_Ratio = uiaxes(app.Tab_Old_Slip_Grid);
            title(app.Old_Front_Slip_Ratio, '旧计算方法的前轮滑移率')
            xlabel(app.Old_Front_Slip_Ratio, 't (s)')
            ylabel(app.Old_Front_Slip_Ratio, '值')
            zlabel(app.Old_Front_Slip_Ratio, 'Z')
            app.Old_Rear_Slip_Ratio = uiaxes(app.Tab_Old_Slip_Grid);
            title(app.Old_Rear_Slip_Ratio, '旧计算方法的后轮滑移率')
            xlabel(app.Old_Rear_Slip_Ratio, 't (s)')
            ylabel(app.Old_Rear_Slip_Ratio, '值')
            zlabel(app.Old_Rear_Slip_Ratio, 'Z')
            app.Tab_Slip_Compare = uitab(app.Tab_Group_);
            app.Tab_Slip_Compare.Title = '新旧滑移对比';
            app.Slip_Compare_Grid = uigridlayout(app.Tab_Slip_Compare);
            app.Front_Slip_Ratio_Compare = uiaxes(app.Slip_Compare_Grid);
            title(app.Front_Slip_Ratio_Compare, '前轮滑移率对比')
            xlabel(app.Front_Slip_Ratio_Compare, 't (s)')
            ylabel(app.Front_Slip_Ratio_Compare, '值')
            zlabel(app.Front_Slip_Ratio_Compare, 'Z')
            app.Front_Slip_Angle_Compare = uiaxes(app.Slip_Compare_Grid);
            title(app.Front_Slip_Angle_Compare, '前轮滑移角对比')
            xlabel(app.Front_Slip_Angle_Compare, 't (s)')
            ylabel(app.Front_Slip_Angle_Compare, '角度 (deg)')
            zlabel(app.Front_Slip_Angle_Compare, 'Z')
            app.Rear_Slip_Ratio_Compare = uiaxes(app.Slip_Compare_Grid);
            title(app.Rear_Slip_Ratio_Compare, '后轮滑移率对比')
            xlabel(app.Rear_Slip_Ratio_Compare, 't (s)')
            ylabel(app.Rear_Slip_Ratio_Compare, '值')
            zlabel(app.Rear_Slip_Ratio_Compare, 'Z')
            app.Rear_Slip_Angle_Compare = uiaxes(app.Slip_Compare_Grid);
            title(app.Rear_Slip_Angle_Compare, '后轮滑移角对比')
            xlabel(app.Rear_Slip_Angle_Compare, 't (s)')
            ylabel(app.Rear_Slip_Angle_Compare, '角度 (deg)')
            zlabel(app.Rear_Slip_Angle_Compare, 'Z')
            app.Tab_Actsteer = uitab(app.Tab_Group_);
            app.Tab_Actsteer.Title = '转角';
            app.Actsteer_Grid = uigridlayout(app.Tab_Actsteer);
            app.Actsteer_Fig = uiaxes(app.Actsteer_Grid);
            title(app.Actsteer_Fig, '转角')
            xlabel(app.Actsteer_Fig, 't (s)')
            ylabel(app.Actsteer_Fig, '值 (度)')
            zlabel(app.Actsteer_Fig, 'Z')
            app.Tab_Planning_Acc = uitab(app.Tab_Group_);
            app.Tab_Planning_Acc.Title = '规划加速度';
            app.Planning_Acc_Grid = uigridlayout(app.Tab_Planning_Acc);
            app.Planning_acc_Plot = uiaxes(app.Planning_Acc_Grid);
            title(app.Planning_acc_Plot, '规划加速度(和上一时刻vel1预测速度微分)')
            xlabel(app.Planning_acc_Plot, 't (s)')
            ylabel(app.Planning_acc_Plot, 'A (m/s^2)')
            zlabel(app.Planning_acc_Plot, 'Z')
            app.Planning_path_plot = uiaxes(app.Planning_Acc_Grid);
            title(app.Planning_path_plot, '规划路径')
            xlabel(app.Planning_path_plot, 'x (m)')
            ylabel(app.Planning_path_plot, 'y (m)')
            zlabel(app.Planning_path_plot, 'Z')
            app.Planning_vel_plot = uiaxes(app.Planning_Acc_Grid);
            title(app.Planning_vel_plot, '规划速度')
            xlabel(app.Planning_vel_plot, 't (s)')
            ylabel(app.Planning_vel_plot, 'V (m/s)')
            zlabel(app.Planning_vel_plot, 'Z')
            app.Planning_ggv_plot = uiaxes(app.Planning_Acc_Grid);
            title(app.Planning_ggv_plot, 'GGV图')
            xlabel(app.Planning_ggv_plot, 'Ay (m/s^2)')
            ylabel(app.Planning_ggv_plot, 'Ax (m/s^2)')
            zlabel(app.Planning_ggv_plot, 'Z')
            app.Tab_GGV = uitab(app.Tab_Group_);
            app.Tab_GGV.Title = 'GGV绘制';
            app.GGV_Grid = uigridlayout(app.Tab_GGV);
            app.GGV_plot = uiaxes(app.GGV_Grid);
            title(app.GGV_plot, 'GGV')
            xlabel(app.GGV_plot, 'ay (m/s^2)')
            ylabel(app.GGV_plot, 'ax (m/s^2)')
            zlabel(app.GGV_plot, 'Z')
            % Show the figure after all components are created
            app.A2RL_UIFigure_.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = A2RL_Planner_Analyzer

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.A2RL_UIFigure_)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.A2RL_UIFigure_)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.A2RL_UIFigure_)
        end
    end
end